classdef control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InteractiveResourceImpactSimulationUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        PodInformationButton            matlab.ui.control.Button
        EnvironmentalConditionsButton   matlab.ui.control.Button
        ProgramDataButton               matlab.ui.control.Button
        InitialConditionsButton         matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        ExportButton                    matlab.ui.control.Button
        GraphsButton                    matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        Image_6                         matlab.ui.control.Image
        IRIS142Label                    matlab.ui.control.Label
        JoshuaAngeJonathanAndrewsAmberGloryLabel  matlab.ui.control.Label
        NASAButton                      matlab.ui.control.Button
        GoogleSitesButton               matlab.ui.control.Button
        GithubButton                    matlab.ui.control.Button
        EnvironmentalConditionsTab      matlab.ui.container.Tab
        Image_2                         matlab.ui.control.Image
        EnvironmentalConditionsLabel    matlab.ui.control.Label
        AtmosphericDensityEditFieldLabel  matlab.ui.control.Label
        AtmosphericDensityEditField     matlab.ui.control.NumericEditField
        GravitationalAccelerationEditFieldLabel  matlab.ui.control.Label
        GravitationalAccelerationEditField  matlab.ui.control.NumericEditField
        YoungsModulusEditFieldLabel     matlab.ui.control.Label
        YoungsModulusEditField          matlab.ui.control.EditField
        SurfaceTemperatureEditFieldLabel  matlab.ui.control.Label
        SurfaceTemperatureEditField     matlab.ui.control.EditField
        ShearModulusEditFieldLabel      matlab.ui.control.Label
        ShearModulusEditField           matlab.ui.control.EditField
        HeightmapEditFieldLabel         matlab.ui.control.Label
        HeightmapEditField              matlab.ui.control.EditField
        BackButton_6                    matlab.ui.control.Button
        ProgramDataTab                  matlab.ui.container.Tab
        Image_3                         matlab.ui.control.Image
        ProgramDataLabel                matlab.ui.control.Label
        MinPerpendicularEditFieldLabel  matlab.ui.control.Label
        MinPerpendicularEditField       matlab.ui.control.NumericEditField
        TimeSegmentLengthEditFieldLabel  matlab.ui.control.Label
        TimeSegmentLengthEditField      matlab.ui.control.NumericEditField
        GraphMinEditFieldLabel          matlab.ui.control.Label
        GraphMinEditField               matlab.ui.control.NumericEditField
        GraphTimeIntervalEditFieldLabel  matlab.ui.control.Label
        GraphTimeIntervalEditField      matlab.ui.control.NumericEditField
        MinEdgeDistanceEditFieldLabel   matlab.ui.control.Label
        MinEdgeDistanceEditField        matlab.ui.control.NumericEditField
        MinSurfaceDistanceEditFieldLabel  matlab.ui.control.Label
        MinSurfaceDistanceEditField     matlab.ui.control.NumericEditField
        MinVelocityMagnitudeEditFieldLabel  matlab.ui.control.Label
        MinVelocityMagnitudeEditField   matlab.ui.control.NumericEditField
        MinCollisionDistanceEditFieldLabel  matlab.ui.control.Label
        MinCollisionDistanceEditField   matlab.ui.control.NumericEditField
        MinTimeofFlightEditFieldLabel   matlab.ui.control.Label
        MinTimeofFlightEditField        matlab.ui.control.NumericEditField
        MaxTimeofFlightEditFieldLabel   matlab.ui.control.Label
        MaxTimeofFlightEditField        matlab.ui.control.NumericEditField
        RangeofPerpendicularMagEditFieldLabel  matlab.ui.control.Label
        RangeofPerpendicularMagEditField  matlab.ui.control.NumericEditField
        TimeSteppingIntervalEditFieldLabel  matlab.ui.control.Label
        TimeSteppingIntervalEditField   matlab.ui.control.NumericEditField
        MaxiIterationsEditFieldLabel    matlab.ui.control.Label
        MaxiIterationsEditField         matlab.ui.control.NumericEditField
        MaxjIterationsEditFieldLabel    matlab.ui.control.Label
        MaxjIterationsEditField         matlab.ui.control.NumericEditField
        GraphIntervalEditFieldLabel     matlab.ui.control.Label
        GraphIntervalEditField          matlab.ui.control.NumericEditField
        GraphMaxEditFieldLabel          matlab.ui.control.Label
        GraphMaxEditField               matlab.ui.control.NumericEditField
        BackButton_4                    matlab.ui.control.Button
        PodInformationTab               matlab.ui.container.Tab
        BackButton_3                    matlab.ui.control.Button
        Image_4                         matlab.ui.control.Image
        PodInformationLabel             matlab.ui.control.Label
        DragCoefficientEditFieldLabel   matlab.ui.control.Label
        DragCoefficientEditField        matlab.ui.control.NumericEditField
        TotalMassEditFieldLabel         matlab.ui.control.Label
        TotalMassEditField              matlab.ui.control.NumericEditField
        ShearModulusEditField_2Label    matlab.ui.control.Label
        ShearModulusEditField_2         matlab.ui.control.NumericEditField
        MomentofInertiaEditFieldLabel   matlab.ui.control.Label
        MomentofInertiaEditField        matlab.ui.control.NumericEditField
        YoungsModulusEditField_2Label   matlab.ui.control.Label
        YoungsModulusEditField_2        matlab.ui.control.NumericEditField
        DiameterEditFieldLabel          matlab.ui.control.Label
        DiameterEditField               matlab.ui.control.NumericEditField
        HeatCapacityEditFieldLabel      matlab.ui.control.Label
        HeatCapacityEditField           matlab.ui.control.NumericEditField
        HeatRatioEditFieldLabel         matlab.ui.control.Label
        HeatRatioEditField              matlab.ui.control.NumericEditField
        DensityEditFieldLabel           matlab.ui.control.Label
        DensityEditField                matlab.ui.control.NumericEditField
        MaxServiceTemperatureEditFieldLabel  matlab.ui.control.Label
        MaxServiceTemperatureEditField  matlab.ui.control.NumericEditField
        MaxTemperatureDifferentialEditFieldLabel  matlab.ui.control.Label
        MaxTemperatureDifferentialEditField  matlab.ui.control.NumericEditField
        MaxDeformationEditFieldLabel    matlab.ui.control.Label
        MaxDeformationEditField         matlab.ui.control.NumericEditField
        YieldStrengthEditFieldLabel     matlab.ui.control.Label
        YieldStrengthEditField          matlab.ui.control.NumericEditField
        FractureToughnessEditFieldLabel  matlab.ui.control.Label
        FractureToughnessEditField      matlab.ui.control.NumericEditField
        MaxForceEditFieldLabel          matlab.ui.control.Label
        MaxForceEditField               matlab.ui.control.NumericEditField
        TensileStrengthEditFieldLabel   matlab.ui.control.Label
        TensileStrengthEditField        matlab.ui.control.NumericEditField
        MaxForcecEditFieldLabel         matlab.ui.control.Label
        MaxForcecEditField              matlab.ui.control.NumericEditField
        MaxServiceTemperaturecEditFieldLabel  matlab.ui.control.Label
        MaxServiceTemperaturecEditField  matlab.ui.control.NumericEditField
        MaxAngularAccelerationcEditFieldLabel  matlab.ui.control.Label
        MaxAngularAccelerationcEditField  matlab.ui.control.NumericEditField
        StiffnessEditFieldLabel         matlab.ui.control.Label
        StiffnessEditField              matlab.ui.control.NumericEditField
        COFEditFieldLabel               matlab.ui.control.Label
        COFEditField                    matlab.ui.control.EditField
        RollingCOFEditFieldLabel        matlab.ui.control.Label
        RollingCOFEditField             matlab.ui.control.EditField
        COREditFieldLabel               matlab.ui.control.Label
        COREditField                    matlab.ui.control.EditField
        ThermalConductivityEditFieldLabel  matlab.ui.control.Label
        ThermalConductivityEditField    matlab.ui.control.EditField
        InitialConditionsTab            matlab.ui.container.Tab
        Image_5                         matlab.ui.control.Image
        InitialConditionsLabel          matlab.ui.control.Label
        yVelocityComponentEditFieldLabel  matlab.ui.control.Label
        yVelocityComponentEditField     matlab.ui.control.NumericEditField
        iSpinComponentEditFieldLabel    matlab.ui.control.Label
        iSpinComponentEditField         matlab.ui.control.NumericEditField
        jSpinComponentEditFieldLabel    matlab.ui.control.Label
        jSpinComponentEditField         matlab.ui.control.NumericEditField
        yLaunchCoordinateEditFieldLabel  matlab.ui.control.Label
        yLaunchCoordinateEditField      matlab.ui.control.NumericEditField
        zLaunchCoordinateEditFieldLabel  matlab.ui.control.Label
        zLaunchCoordinateEditField      matlab.ui.control.NumericEditField
        kSpinComponentEditFieldLabel    matlab.ui.control.Label
        kSpinComponentEditField         matlab.ui.control.NumericEditField
        PodTemperatureEditFieldLabel    matlab.ui.control.Label
        PodTemperatureEditField         matlab.ui.control.NumericEditField
        xVelocityComponentEditFieldLabel  matlab.ui.control.Label
        xVelocityComponentEditField     matlab.ui.control.NumericEditField
        xLaunchCoordinateEditFieldLabel  matlab.ui.control.Label
        xLaunchCoordinateEditField      matlab.ui.control.NumericEditField
        zVelocityComponentEditField     matlab.ui.control.NumericEditField
        zVelocityComponentEditFieldLabel  matlab.ui.control.Label
        BackButton_5                    matlab.ui.control.Button
    end

    
    properties (Access = public)
        folder = pwd;
        file = 'App';
        % Environmental Conditions
        value_funcG_l;
        value_funcL;
        value_funcT_g;
        value_funcY_l;
        value_g;
        value_rho;
        % Program Data
        value_B_m_min;
        value_T;
        value_domainInt;
        value_domainMax;
        value_domainMin;
        value_T_units;
        value_i_max;
        value_j_max;
        value_r_edge;
        value_r_min;
        value_s_min;
        value_t_p_min;
        value_t_p_max;
        value_M_range;
        value_t_step;
        value_v_min;
        % Pod Information
        value_C_d;
        value_m;
        value_funcF;
        value_funcF_r;
        value_G_p;
        value_I;
        value_funcK;
        value_funcT_c;
        value_Y_p;
        value_d;
        value_h_C;
        value_h_R;
        value_rho_p;
        value_T_max;
        value_deltaT;
        value_d_shell;
        value_E_l;
        value_K_c;
        value_FI_max;
        value_F_tu;
        value_FI_max_c;
        value_T_max_c;
        value_omega_max_c;
        value_k_H;
        % Initial Conditions
        value_T_i;
        value_startX;
        value_startY;
        value_startZ;
        value_q_iX;
        value_q_iY;
        value_q_iZ;
        value_u_iX;
        value_u_iY;
        value_u_iZ;
    end
    
    properties (Access = private)
        DialogApp;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.folder = pwd;
            app.file = 'App';
            app.value_funcG_l = '0*x + 0*y';
            app.value_funcL = '0*x + 0*y';
            app.value_funcT_g = '0*x + 0*y';
            app.value_funcY_l = '0*x + 0*y';
            app.value_g = 0;
            app.value_rho = 0;
            app.value_B_m_min = 0;
            app.value_T = 0;
            app.value_domainInt = 0;
            app.value_domainMax = 0;
            app.value_domainMin = 0;
            app.value_T_units = 0;
            app.value_i_max = 0;
            app.value_j_max = 0;
            app.value_r_edge = 0;
            app.value_r_min = 0;
            app.value_s_min = 0;
            app.value_t_p_min = 0;
            app.value_t_p_max = 0;
            app.value_M_range = 0;
            app.value_t_step = 0;
            app.value_v_min = 0;
            app.value_C_d = 0;
            app.value_m = 0;
            app.value_funcF = '0*x + 0*y';
            app.value_funcF_r = '0*x + 0*y';
            app.value_G_p = 0;
            app.value_I = 0;
            app.value_funcK = '0*x + 0*y';
            app.value_funcT_c = '0*x + 0*y';
            app.value_Y_p = 0;
            app.value_d = 0;
            app.value_h_C = 0;
            app.value_h_R = 0;
            app.value_rho_p = 0;
            app.value_T_max = 0;
            app.value_deltaT = 0;
            app.value_d_shell = 0;
            app.value_E_l = 0;
            app.value_K_c = 0;
            app.value_FI_max = 0;
            app.value_F_tu = 0;
            app.value_FI_max_c = 0;
            app.value_T_max_c = 0;
            app.value_omega_max_c = 0;
            app.value_k_H = 0;
            app.value_T_i = 0;
            app.value_startX = 0;
            app.value_startY = 0;
            app.value_startZ = 0;
            app.value_q_iX = 0;
            app.value_q_iY = 0;
            app.value_q_iZ = 0;
            app.value_u_iX = 0;
            app.value_u_iY = 0;
            app.value_u_iZ = 0;
        end

        % Button pushed function: InitialConditionsButton
        function InitialConditionsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.InitialConditionsTab;
        end

        % Button pushed function: PodInformationButton
        function PodInformationButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.PodInformationTab;
        end

        % Button pushed function: EnvironmentalConditionsButton
        function EnvironmentalConditionsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.EnvironmentalConditionsTab;
        end

        % Button pushed function: ProgramDataButton
        function ProgramDataButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ProgramDataTab;
        end

        % Button pushed function: GraphsButton
        function GraphsButtonPushed(app, event)
            load(strcat(app.folder,'\main\outputs\',app.file,'.mat'));
            run(strcat(app.folder,'\out\main\main.m'));
            run(strcat(app.folder,'\out\kineticEnergy.m'));
            run(strcat(app.folder,'\out\deformation.m'));
            run(strcat(app.folder,'\out\reactions.m'));
            run(strcat(app.folder,'\out\velocity.m'));
            run(strcat(app.folder,'\out\temperature.m'));
            run(strcat(app.folder,'\out\spin.m'));
            run(strcat(app.folder,'\out\survivability.m'));
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            load(strcat(app.folder,'\main\outputs\',app.file,'.mat'));
            run(strcat(app.folder,'\record\export.m'))
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            syms x y L(x,y);
            T_i=app.value_T_i;
            q_i=[app.value_startX, app.value_startY, app.value_startZ ; app.value_q_iX, app.value_q_iY, app.value_q_iZ];
            u_i=[app.value_startX, app.value_startY, app.value_startZ ; app.value_u_iX, app.value_u_iY, app.value_u_iZ];
            C_d=app.value_C_d;
            m=app.value_m;
            F=str2func(strcat('@(x,y)',app.value_funcF));
            F_r=str2func(strcat('@(x,y)',app.value_funcF_r));
            G_p=app.value_G_p;
            I=app.value_I;
            K=str2func(strcat('@(x,y)',app.value_funcK));
            T_c=str2func(strcat('@(x,y)',app.value_funcT_c));
            Y_p=app.value_Y_p;
            d=app.value_d;
            h_C=app.value_h_C;
            h_R=app.value_h_R;
            rho_p=app.value_rho_p;
            T_max=app.value_T_max;
            deltaT=app.value_deltaT;
            d_shell=app.value_d_shell;
            E_l=app.value_E_l;
            K_c=app.value_K_c;
            FI_max=app.value_FI_max;
            F_tu=app.value_F_tu;
            FI_max_c=app.value_FI_max_c;
            T_max_c=app.value_T_max_c;
            omega_max_c=app.value_omega_max_c;
            k_H=app.value_k_H;
            rho=app.value_rho;
            G_l=str2func(strcat('@(x,y)',app.value_funcG_l));
            L=symfun(str2sym(app.value_funcL),[x,y]);
            g=app.value_g;
            Y_l=str2func(strcat('@(x,y)',app.value_funcY_l));
            T_g=str2func(strcat('@(x,y)',app.value_funcT_g));
            B_m_min=app.value_B_m_min;
            T=app.value_T;
            domainInt=app.value_domainInt;
            domainMax=app.value_domainMax;
            domainMin=app.value_domainMin;
            T_units=app.value_T_units;
            i_max=app.value_i_max;
            j_max=app.value_j_max;
            r_edge=app.value_r_edge;
            r_min=app.value_r_min;
            s_min=app.value_s_min;
            t_p_min=app.value_t_p_min;
            t_p_max=app.value_t_p_max;
            M_range=app.value_M_range;
            t_step=app.value_t_step;
            v_min=app.value_v_min;
            save(strcat(app.folder,'\main\inputs\',app.file,'.mat'),'T_i','q_i','u_i','C_d','m','F','F_r','G_p','I','K','T_c','Y_p','d','h_C','h_R','rho_p','T_max','deltaT','d_shell','E_l','K_c','FI_max','F_tu','FI_max_c','T_max_c','omega_max_c','k_H','rho','G_l','L','g','Y_l','T_g','B_m_min','T','domainInt','domainMax','domainMin','T_units','i_max','j_max','r_edge','r_min','s_min','t_p_min','t_p_max','M_range','t_step','v_min','-nocompression')
            run(strcat(app.folder,'\start.m'));
        end

        % Callback function: BackButton_3, BackButton_4, 
        % BackButton_5, BackButton_6, PodInformationTab, 
        % ProgramDataTab
        function BackButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.MainTab;
        end

        % Value changed function: AtmosphericDensityEditField
        function AtmosphericDensityEditFieldValueChanged(app, event)
            app.value_rho = app.AtmosphericDensityEditField.Value;
            
        end

        % Value changed function: GravitationalAccelerationEditField
        function GravitationalAccelerationEditFieldValueChanged(app, event)
            app.value_g = app.GravitationalAccelerationEditField.Value;
            
        end

        % Value changed function: ShearModulusEditField
        function ShearModulusEditFieldValueChanged(app, event)
            app.value_funcG_l = app.ShearModulusEditField.Value;
            
        end

        % Value changed function: YoungsModulusEditField
        function YoungsModulusEditFieldValueChanged(app, event)
            app.value_funcY_l = app.YoungsModulusEditField.Value;
            
        end

        % Value changed function: HeightmapEditField
        function HeightmapEditFieldValueChanged(app, event)
            app.value_funcL = app.HeightmapEditField.Value;
            
        end

        % Value changed function: SurfaceTemperatureEditField
        function SurfaceTemperatureEditFieldValueChanged(app, event)
            app.value_funcT_g = app.SurfaceTemperatureEditField.Value;
            
        end

        % Value changed function: MinPerpendicularEditField
        function MinPerpendicularEditFieldValueChanged(app, event)
            app.value_B_m_min = app.MinPerpendicularEditField.Value;
            
        end

        % Value changed function: TimeSegmentLengthEditField
        function TimeSegmentLengthEditFieldValueChanged(app, event)
            app.value_T = app.TimeSegmentLengthEditField.Value;
            
        end

        % Value changed function: GraphIntervalEditField
        function GraphIntervalEditFieldValueChanged(app, event)
            app.value_domainInt = app.GraphIntervalEditField.Value;
            
        end

        % Value changed function: GraphMaxEditField
        function GraphMaxEditFieldValueChanged(app, event)
            app.value_domainMax = app.GraphMaxEditField.Value;
            
        end

        % Value changed function: GraphMinEditField
        function GraphMinEditFieldValueChanged(app, event)
            app.value_domainMin = app.GraphMinEditField.Value;
            
        end

        % Value changed function: GraphTimeIntervalEditField
        function GraphTimeIntervalEditFieldValueChanged(app, event)
            app.value_T_units = app.GraphTimeIntervalEditField.Value;
            
        end

        % Value changed function: MaxiIterationsEditField
        function MaxiIterationsEditFieldValueChanged(app, event)
            app.value_i_max = app.MaxiIterationsEditField.Value;
            
        end

        % Value changed function: MaxjIterationsEditField
        function MaxjIterationsEditFieldValueChanged(app, event)
            app.value_j_max = app.MaxjIterationsEditField.Value;
            
        end

        % Value changed function: MinEdgeDistanceEditField
        function MinEdgeDistanceEditFieldValueChanged(app, event)
            app.value_r_edge = app.MinEdgeDistanceEditField.Value;
            
        end

        % Value changed function: MinSurfaceDistanceEditField
        function MinSurfaceDistanceEditFieldValueChanged(app, event)
            app.value_r_min = app.MinSurfaceDistanceEditField.Value;
            
        end

        % Value changed function: MinVelocityMagnitudeEditField
        function MinVelocityMagnitudeEditFieldValueChanged(app, event)
            app.value_v_min = app.MinVelocityMagnitudeEditField.Value;
            
        end

        % Value changed function: MinCollisionDistanceEditField
        function MinCollisionDistanceEditFieldValueChanged(app, event)
            app.value_s_min = app.MinCollisionDistanceEditField.Value;
            
        end

        % Value changed function: MinTimeofFlightEditField
        function MinTimeofFlightEditFieldValueChanged(app, event)
            app.value_t_p_min = app.MinTimeofFlightEditField.Value;
            
        end

        % Value changed function: MaxTimeofFlightEditField
        function MaxTimeofFlightEditFieldValueChanged(app, event)
            app.value_t_p_max = app.MaxTimeofFlightEditField.Value;
            
        end

        % Value changed function: RangeofPerpendicularMagEditField
        function RangeofPerpendicularMagEditFieldValueChanged(app, event)
            app.value_M_range = app.RangeofPerpendicularMagEditField.Value;
            
        end

        % Value changed function: TimeSteppingIntervalEditField
        function TimeSteppingIntervalEditFieldValueChanged(app, event)
            app.value_t_step = app.TimeSteppingIntervalEditField.Value;
            
        end

        % Value changed function: DragCoefficientEditField
        function DragCoefficientEditFieldValueChanged(app, event)
            app.value_C_d = app.DragCoefficientEditField.Value;
            
        end

        % Value changed function: TotalMassEditField
        function TotalMassEditFieldValueChanged(app, event)
            app.value_m = app.TotalMassEditField.Value;
            
        end

        % Value changed function: COFEditField
        function COFEditFieldValueChanged(app, event)
            app.value_funcF = app.COFEditField.Value;
            
        end

        % Value changed function: RollingCOFEditField
        function RollingCOFEditFieldValueChanged(app, event)
            app.value_funcF_r = app.RollingCOFEditField.Value;
            
        end

        % Value changed function: ShearModulusEditField_2
        function ShearModulusEditField_2ValueChanged(app, event)
            app.value_G_p = app.ShearModulusEditField_2.Value;
            
        end

        % Value changed function: MomentofInertiaEditField
        function MomentofInertiaEditFieldValueChanged(app, event)
            app.value_I = app.MomentofInertiaEditField.Value;
            
        end

        % Value changed function: COREditField
        function COREditFieldValueChanged(app, event)
            app.value_funcK = app.COREditField.Value;
            
        end

        % Value changed function: ThermalConductivityEditField
        function ThermalConductivityEditFieldValueChanged(app, event)
            app.value_funcT_c = app.ThermalConductivityEditField.Value;
            
        end

        % Value changed function: YoungsModulusEditField_2
        function YoungsModulusEditField_2ValueChanged(app, event)
            app.value_Y_p = app.YoungsModulusEditField_2.Value;
            
        end

        % Value changed function: DiameterEditField
        function DiameterEditFieldValueChanged(app, event)
            app.value_d = app.DiameterEditField.Value;
            
        end

        % Value changed function: HeatCapacityEditField
        function HeatCapacityEditFieldValueChanged(app, event)
            app.value_h_C = app.HeatCapacityEditField.Value;
            
        end

        % Value changed function: HeatRatioEditField
        function HeatRatioEditFieldValueChanged(app, event)
            app.value_h_R = app.HeatRatioEditField.Value;
            
        end

        % Value changed function: DensityEditField
        function DensityEditFieldValueChanged(app, event)
            app.value_rho_p = app.DensityEditField.Value;
            
        end

        % Value changed function: MaxServiceTemperatureEditField
        function MaxServiceTemperatureEditFieldValueChanged(app, event)
            app.value_T_max = app.MaxServiceTemperatureEditField.Value;
            
        end

        % Value changed function: 
        % MaxTemperatureDifferentialEditField
        function MaxTemperatureDifferentialEditFieldValueChanged(app, event)
            app.value_deltaT = app.MaxTemperatureDifferentialEditField.Value;
            
        end

        % Value changed function: MaxDeformationEditField
        function MaxDeformationEditFieldValueChanged(app, event)
            app.value_d_shell = app.MaxDeformationEditField.Value;
            
        end

        % Value changed function: YieldStrengthEditField
        function YieldStrengthEditFieldValueChanged(app, event)
            app.value_E_l = app.YieldStrengthEditField.Value;
            
        end

        % Value changed function: FractureToughnessEditField
        function FractureToughnessEditFieldValueChanged(app, event)
            app.value_K_c = app.FractureToughnessEditField.Value;
            
        end

        % Value changed function: MaxForceEditField
        function MaxForceEditFieldValueChanged(app, event)
            app.value_FI_max = app.MaxForceEditField.Value;
            
        end

        % Value changed function: TensileStrengthEditField
        function TensileStrengthEditFieldValueChanged(app, event)
            app.value_F_tu = app.TensileStrengthEditField.Value;
            
        end

        % Value changed function: MaxForcecEditField
        function MaxForcecEditFieldValueChanged(app, event)
            app.value_FI_max_c = app.MaxForcecEditField.Value;
            
        end

        % Value changed function: MaxServiceTemperaturecEditField
        function MaxServiceTemperaturecEditFieldValueChanged(app, event)
            app.value_T_max_c = app.MaxServiceTemperaturecEditField.Value;
            
        end

        % Value changed function: MaxAngularAccelerationcEditField
        function MaxAngularAccelerationcEditFieldValueChanged(app, event)
            app.value_omega_max_c = app.MaxAngularAccelerationcEditField.Value;
            
        end

        % Value changed function: StiffnessEditField
        function StiffnessEditFieldValueChanged(app, event)
            app.value_k_H = app.StiffnessEditField.Value;
            
        end

        % Value changed function: iSpinComponentEditField
        function iSpinComponentEditFieldValueChanged(app, event)
            app.value_q_iX = app.iSpinComponentEditField.Value;
            
        end

        % Value changed function: jSpinComponentEditField
        function jSpinComponentEditFieldValueChanged(app, event)
            app.value_q_iY = app.jSpinComponentEditField.Value;
            
        end

        % Value changed function: kSpinComponentEditField
        function kSpinComponentEditFieldValueChanged(app, event)
            app.value_q_iZ = app.kSpinComponentEditField.Value;
            
        end

        % Value changed function: PodTemperatureEditField
        function PodTemperatureEditFieldValueChanged(app, event)
            app.value_T_i = app.PodTemperatureEditField.Value;
            
        end

        % Value changed function: xVelocityComponentEditField
        function xVelocityComponentEditFieldValueChanged(app, event)
            app.value_u_iX = app.xVelocityComponentEditField.Value;
            
        end

        % Value changed function: yVelocityComponentEditField
        function yVelocityComponentEditFieldValueChanged(app, event)
            app.value_u_iY = app.yVelocityComponentEditField.Value;
            
        end

        % Value changed function: zVelocityComponentEditField
        function zVelocityComponentEditFieldValueChanged(app, event)
            app.value_u_iZ = app.zVelocityComponentEditField.Value;
            
        end

        % Value changed function: xLaunchCoordinateEditField
        function xLaunchCoordinateEditFieldValueChanged(app, event)
            app.value_startX = app.xLaunchCoordinateEditField.Value;
            
        end

        % Value changed function: yLaunchCoordinateEditField
        function yLaunchCoordinateEditFieldValueChanged(app, event)
            app.value_startY = app.yLaunchCoordinateEditField.Value;
            
        end

        % Value changed function: zLaunchCoordinateEditField
        function zLaunchCoordinateEditFieldValueChanged(app, event)
            app.value_startZ = app.zLaunchCoordinateEditField.Value;
            
        end

        % Button pushed function: GithubButton
        function GithubButtonPushed(app, event)
            web('https://github.com/joshuaange/IRIS');
        end

        % Button pushed function: GoogleSitesButton
        function GoogleSitesButtonPushed(app, event)
            web('https://sites.google.com/view/lunariris');
        end

        % Button pushed function: NASAButton
        function NASAButtonPushed(app, event)
            web('http://www.hunchdesign.com/');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InteractiveResourceImpactSimulationUIFigure = uifigure('Visible', 'off');
            app.InteractiveResourceImpactSimulationUIFigure.Name = 'Interactive Resource Impact Simulation';
            app.TabGroup = uitabgroup(app.InteractiveResourceImpactSimulationUIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.PodInformationButton = uibutton(app.MainTab, 'push');
            app.PodInformationButton.ButtonPushedFcn = createCallbackFcn(app, @PodInformationButtonPushed, true);
            app.PodInformationButton.Text = {'Pod'; 'Information'};
            app.EnvironmentalConditionsButton = uibutton(app.MainTab, 'push');
            app.EnvironmentalConditionsButton.ButtonPushedFcn = createCallbackFcn(app, @EnvironmentalConditionsButtonPushed, true);
            app.EnvironmentalConditionsButton.Text = {'Environmental'; 'Conditions'};
            app.ProgramDataButton = uibutton(app.MainTab, 'push');
            app.ProgramDataButton.ButtonPushedFcn = createCallbackFcn(app, @ProgramDataButtonPushed, true);
            app.ProgramDataButton.Text = {'Program'; 'Data'};
            app.InitialConditionsButton = uibutton(app.MainTab, 'push');
            app.InitialConditionsButton.ButtonPushedFcn = createCallbackFcn(app, @InitialConditionsButtonPushed, true);
            app.InitialConditionsButton.Text = {'Initial'; 'Conditions'};
            app.RunButton = uibutton(app.MainTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ExportButton = uibutton(app.MainTab, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.GraphsButton = uibutton(app.MainTab, 'push');
            app.GraphsButton.ButtonPushedFcn = createCallbackFcn(app, @GraphsButtonPushed, true);
            app.GraphsButton.Text = 'Graphs';
            app.Image2 = uiimage(app.MainTab);
            app.Image2.ImageSource = 'Blue_Background.png';
            app.Image_6 = uiimage(app.MainTab);
            app.Image_6.ImageSource = 'IRIS_White.png';
            app.IRIS142Label = uilabel(app.MainTab);
            app.IRIS142Label.Text = 'IRIS 1.4.2';
            app.JoshuaAngeJonathanAndrewsAmberGloryLabel = uilabel(app.MainTab);
            app.JoshuaAngeJonathanAndrewsAmberGloryLabel.Text = 'Joshua Ange, Jonathan Andrews, Amber Glory';
            app.NASAButton = uibutton(app.MainTab, 'push');
            app.NASAButton.ButtonPushedFcn = createCallbackFcn(app, @NASAButtonPushed, true);
            app.NASAButton.Icon = 'nasa vector.png';
            app.NASAButton.Text = '';
            app.GoogleSitesButton = uibutton(app.MainTab, 'push');
            app.GoogleSitesButton.ButtonPushedFcn = createCallbackFcn(app, @GoogleSitesButtonPushed, true);
            app.GoogleSitesButton.Icon = 'Google Site.png';
            app.GoogleSitesButton.Text = '';
            app.GithubButton = uibutton(app.MainTab, 'push');
            app.GithubButton.ButtonPushedFcn = createCallbackFcn(app, @GithubButtonPushed, true);
            app.GithubButton.Icon = 'Github.png';
            app.GithubButton.Text = '';
            app.EnvironmentalConditionsTab = uitab(app.TabGroup);
            app.EnvironmentalConditionsTab.Title = 'Environmental Conditions';
            app.Image_2 = uiimage(app.EnvironmentalConditionsTab);
            app.Image_2.ImageSource = 'IRIS_Black.png';
            app.EnvironmentalConditionsLabel = uilabel(app.EnvironmentalConditionsTab);
            app.EnvironmentalConditionsLabel.Text = 'Environmental Conditions';
            app.AtmosphericDensityEditFieldLabel = uilabel(app.EnvironmentalConditionsTab);
            app.AtmosphericDensityEditFieldLabel.Tooltip = {''};
            app.AtmosphericDensityEditFieldLabel.Text = 'Atmospheric Density';
            app.AtmosphericDensityEditField = uieditfield(app.EnvironmentalConditionsTab, 'numeric');
            app.AtmosphericDensityEditField.ValueChangedFcn = createCallbackFcn(app, @AtmosphericDensityEditFieldValueChanged, true);
            app.AtmosphericDensityEditField.Tooltip = {'Atmospheric density (kg/m^3)'};
            app.GravitationalAccelerationEditFieldLabel = uilabel(app.EnvironmentalConditionsTab);
            app.GravitationalAccelerationEditFieldLabel.Text = 'Gravitational Acceleration';
            app.GravitationalAccelerationEditField = uieditfield(app.EnvironmentalConditionsTab, 'numeric');
            app.GravitationalAccelerationEditField.ValueChangedFcn = createCallbackFcn(app, @GravitationalAccelerationEditFieldValueChanged, true);
            app.GravitationalAccelerationEditField.Tooltip = {'Gravitational acceleration (m/s^2)'};
            app.YoungsModulusEditFieldLabel = uilabel(app.EnvironmentalConditionsTab);
            app.YoungsModulusEditFieldLabel.Text = 'Young''s Modulus';
            app.YoungsModulusEditField = uieditfield(app.EnvironmentalConditionsTab, 'text');
            app.YoungsModulusEditField.ValueChangedFcn = createCallbackFcn(app, @YoungsModulusEditFieldValueChanged, true);
            app.YoungsModulusEditField.Tooltip = {'Function defining Young’s Modulus of surface location (x, y, L(x,y))  (gf/mm^2)'};
            app.YoungsModulusEditField.Value = '0*x + 0*y';
            app.SurfaceTemperatureEditFieldLabel = uilabel(app.EnvironmentalConditionsTab);
            app.SurfaceTemperatureEditFieldLabel.Text = 'Surface Temperature';
            app.SurfaceTemperatureEditField = uieditfield(app.EnvironmentalConditionsTab, 'text');
            app.SurfaceTemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @SurfaceTemperatureEditFieldValueChanged, true);
            app.SurfaceTemperatureEditField.Tooltip = {'Function defining temperature of surface location (x, y, L(x,y))  (K)'};
            app.SurfaceTemperatureEditField.Value = '0*x + 0*y';
            app.ShearModulusEditFieldLabel = uilabel(app.EnvironmentalConditionsTab);
            app.ShearModulusEditFieldLabel.Text = 'Shear Modulus';
            app.ShearModulusEditField = uieditfield(app.EnvironmentalConditionsTab, 'text');
            app.ShearModulusEditField.ValueChangedFcn = createCallbackFcn(app, @ShearModulusEditFieldValueChanged, true);
            app.ShearModulusEditField.Tooltip = {'Function defining Modulus of Rigidity of surface location (x, y, L(x,y))  (gf/mm^2)'};
            app.ShearModulusEditField.Value = '0*x + 0*y';
            app.HeightmapEditFieldLabel = uilabel(app.EnvironmentalConditionsTab);
            app.HeightmapEditFieldLabel.Text = 'Heightmap';
            app.HeightmapEditField = uieditfield(app.EnvironmentalConditionsTab, 'text');
            app.HeightmapEditField.ValueChangedFcn = createCallbackFcn(app, @HeightmapEditFieldValueChanged, true);
            app.HeightmapEditField.Tooltip = {'Function defining heightmap of lunar surface (m) (piecewise functions must be entered directly into script "start.m")'};
            app.HeightmapEditField.Value = '0*x + 0*y';
            app.BackButton_6 = uibutton(app.EnvironmentalConditionsTab, 'push');
            app.BackButton_6.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton_6.Text = 'Back';
            app.ProgramDataTab = uitab(app.TabGroup);
            app.ProgramDataTab.SizeChangedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.ProgramDataTab.Title = 'Program Data';
            app.Image_3 = uiimage(app.ProgramDataTab);
            app.Image_3.ImageSource = 'IRIS_Black.png';
            app.ProgramDataLabel = uilabel(app.ProgramDataTab);
            app.ProgramDataLabel.Text = 'Program Data';
            app.MinPerpendicularEditFieldLabel = uilabel(app.ProgramDataTab);
            app.MinPerpendicularEditFieldLabel.Text = 'Min. Perpendicular';
            app.MinPerpendicularEditField = uieditfield(app.ProgramDataTab, 'numeric');
            app.MinPerpendicularEditField.ValueChangedFcn = createCallbackFcn(app, @MinPerpendicularEditFieldValueChanged, true);
            app.MinPerpendicularEditField.Tooltip = {'Minimum value of perpendicular vector (m/s)'};
            app.TimeSegmentLengthEditFieldLabel = uilabel(app.ProgramDataTab);
            app.TimeSegmentLengthEditFieldLabel.Text = 'Time Segment Length';
            app.TimeSegmentLengthEditField = uieditfield(app.ProgramDataTab, 'numeric');
            app.TimeSegmentLengthEditField.ValueChangedFcn = createCallbackFcn(app, @TimeSegmentLengthEditFieldValueChanged, true);
            app.TimeSegmentLengthEditField.Tooltip = {'Length of time segments (s)'};
            app.GraphMinEditFieldLabel = uilabel(app.ProgramDataTab);
            app.GraphMinEditFieldLabel.Text = 'Graph Min.';
            app.GraphMinEditField = uieditfield(app.ProgramDataTab, 'numeric');
            app.GraphMinEditField.ValueChangedFcn = createCallbackFcn(app, @GraphMinEditFieldValueChanged, true);
            app.GraphMinEditField.Tooltip = {'Minimum value of displayed surface graph (m)'};
            app.GraphTimeIntervalEditFieldLabel = uilabel(app.ProgramDataTab);
            app.GraphTimeIntervalEditFieldLabel.Text = 'Graph Time Interval';
            app.GraphTimeIntervalEditField = uieditfield(app.ProgramDataTab, 'numeric');
            app.GraphTimeIntervalEditField.ValueChangedFcn = createCallbackFcn(app, @GraphTimeIntervalEditFieldValueChanged, true);
            app.GraphTimeIntervalEditField.Tooltip = {'Interval on graphs for trajectory (s)'};
            app.MinEdgeDistanceEditFieldLabel = uilabel(app.ProgramDataTab);
            app.MinEdgeDistanceEditFieldLabel.Text = 'Min. Edge Distance';
            app.MinEdgeDistanceEditField = uieditfield(app.ProgramDataTab, 'numeric');
            app.MinEdgeDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @MinEdgeDistanceEditFieldValueChanged, true);
            app.MinEdgeDistanceEditField.Tooltip = {'Minimum distance of velocity to be considered above the surface and off an edge (m)'};
            app.MinSurfaceDistanceEditFieldLabel = uilabel(app.ProgramDataTab);
            app.MinSurfaceDistanceEditFieldLabel.Text = 'Min. Surface Distance';
            app.MinSurfaceDistanceEditField = uieditfield(app.ProgramDataTab, 'numeric');
            app.MinSurfaceDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @MinSurfaceDistanceEditFieldValueChanged, true);
            app.MinSurfaceDistanceEditField.Tooltip = {'Minimum distance of velocity to be considered above the surface (m)'};
            app.MinVelocityMagnitudeEditFieldLabel = uilabel(app.ProgramDataTab);
            app.MinVelocityMagnitudeEditFieldLabel.Text = 'Min. Velocity Magnitude';
            app.MinVelocityMagnitudeEditField = uieditfield(app.ProgramDataTab, 'numeric');
            app.MinVelocityMagnitudeEditField.ValueChangedFcn = createCallbackFcn(app, @MinVelocityMagnitudeEditFieldValueChanged, true);
            app.MinVelocityMagnitudeEditField.Tooltip = {'Minimum considered value of velocity (m/s)'};
            app.MinCollisionDistanceEditFieldLabel = uilabel(app.ProgramDataTab);
            app.MinCollisionDistanceEditFieldLabel.Text = 'Min. Collision Distance';
            app.MinCollisionDistanceEditField = uieditfield(app.ProgramDataTab, 'numeric');
            app.MinCollisionDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @MinCollisionDistanceEditFieldValueChanged, true);
            app.MinCollisionDistanceEditField.Tooltip = {'Minimum distance of collision for points along pod (m)'};
            app.MinTimeofFlightEditFieldLabel = uilabel(app.ProgramDataTab);
            app.MinTimeofFlightEditFieldLabel.Text = 'Min. Time of Flight';
            app.MinTimeofFlightEditField = uieditfield(app.ProgramDataTab, 'numeric');
            app.MinTimeofFlightEditField.ValueChangedFcn = createCallbackFcn(app, @MinTimeofFlightEditFieldValueChanged, true);
            app.MinTimeofFlightEditField.Tooltip = {'Minimum time of flight for trajectory (s)'};
            app.MaxTimeofFlightEditFieldLabel = uilabel(app.ProgramDataTab);
            app.MaxTimeofFlightEditFieldLabel.Text = 'Max. Time of Flight';
            app.MaxTimeofFlightEditField = uieditfield(app.ProgramDataTab, 'numeric');
            app.MaxTimeofFlightEditField.ValueChangedFcn = createCallbackFcn(app, @MaxTimeofFlightEditFieldValueChanged, true);
            app.MaxTimeofFlightEditField.Tooltip = {'Maximum time of flight for trajectory (s)'};
            app.RangeofPerpendicularMagEditFieldLabel = uilabel(app.ProgramDataTab);
            app.RangeofPerpendicularMagEditFieldLabel.Text = 'Range of Perpendicular Mag.';
            app.RangeofPerpendicularMagEditField = uieditfield(app.ProgramDataTab, 'numeric');
            app.RangeofPerpendicularMagEditField.ValueChangedFcn = createCallbackFcn(app, @RangeofPerpendicularMagEditFieldValueChanged, true);
            app.RangeofPerpendicularMagEditField.Tooltip = {'Range for magnitude along the perpendicular vector (m)'};
            app.TimeSteppingIntervalEditFieldLabel = uilabel(app.ProgramDataTab);
            app.TimeSteppingIntervalEditFieldLabel.Text = 'Time "Stepping" Interval';
            app.TimeSteppingIntervalEditField = uieditfield(app.ProgramDataTab, 'numeric');
            app.TimeSteppingIntervalEditField.ValueChangedFcn = createCallbackFcn(app, @TimeSteppingIntervalEditFieldValueChanged, true);
            app.TimeSteppingIntervalEditField.Tooltip = {'“Stepping value” for pod considerations during trajectory (s)'};
            app.MaxiIterationsEditFieldLabel = uilabel(app.ProgramDataTab);
            app.MaxiIterationsEditFieldLabel.Text = 'Max i Iterations';
            app.MaxiIterationsEditField = uieditfield(app.ProgramDataTab, 'numeric');
            app.MaxiIterationsEditField.ValueChangedFcn = createCallbackFcn(app, @MaxiIterationsEditFieldValueChanged, true);
            app.MaxiIterationsEditField.Tooltip = {'Maximum number of trajectory iterations'};
            app.MaxjIterationsEditFieldLabel = uilabel(app.ProgramDataTab);
            app.MaxjIterationsEditFieldLabel.Text = 'Max j Iterations';
            app.MaxjIterationsEditField = uieditfield(app.ProgramDataTab, 'numeric');
            app.MaxjIterationsEditField.ValueChangedFcn = createCallbackFcn(app, @MaxjIterationsEditFieldValueChanged, true);
            app.MaxjIterationsEditField.Tooltip = {'Maximum number of time segments per trajectory'};
            app.GraphIntervalEditFieldLabel = uilabel(app.ProgramDataTab);
            app.GraphIntervalEditFieldLabel.Text = 'Graph Interval';
            app.GraphIntervalEditField = uieditfield(app.ProgramDataTab, 'numeric');
            app.GraphIntervalEditField.ValueChangedFcn = createCallbackFcn(app, @GraphIntervalEditFieldValueChanged, true);
            app.GraphIntervalEditField.Tooltip = {'Graphical divisions of displayed surface graph (m)'};
            app.GraphMaxEditFieldLabel = uilabel(app.ProgramDataTab);
            app.GraphMaxEditFieldLabel.Text = 'Graph Max.';
            app.GraphMaxEditField = uieditfield(app.ProgramDataTab, 'numeric');
            app.GraphMaxEditField.ValueChangedFcn = createCallbackFcn(app, @GraphMaxEditFieldValueChanged, true);
            app.GraphMaxEditField.Tooltip = {'Maximum value of displayed surface graph (m)'};
            app.BackButton_4 = uibutton(app.ProgramDataTab, 'push');
            app.BackButton_4.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton_4.Text = 'Back';
            app.PodInformationTab = uitab(app.TabGroup);
            app.PodInformationTab.SizeChangedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.PodInformationTab.Title = 'Pod Information';
            app.BackButton_3 = uibutton(app.PodInformationTab, 'push');
            app.BackButton_3.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton_3.Text = 'Back';
            app.Image_4 = uiimage(app.PodInformationTab);
            app.Image_4.ImageSource = 'IRIS_Black.png';
            app.PodInformationLabel = uilabel(app.PodInformationTab);
            app.PodInformationLabel.Text = 'Pod Information';
            app.DragCoefficientEditFieldLabel = uilabel(app.PodInformationTab);
            app.DragCoefficientEditFieldLabel.Text = 'Drag Coefficient';
            app.DragCoefficientEditField = uieditfield(app.PodInformationTab, 'numeric');
            app.DragCoefficientEditField.ValueChangedFcn = createCallbackFcn(app, @DragCoefficientEditFieldValueChanged, true);
            app.DragCoefficientEditField.Tooltip = {'Drag coefficient of pod (standard 0.5 for most spheres)'};
            app.TotalMassEditFieldLabel = uilabel(app.PodInformationTab);
            app.TotalMassEditFieldLabel.Text = 'Total Mass';
            app.TotalMassEditField = uieditfield(app.PodInformationTab, 'numeric');
            app.TotalMassEditField.ValueChangedFcn = createCallbackFcn(app, @TotalMassEditFieldValueChanged, true);
            app.TotalMassEditField.Tooltip = {'Overall mass of pod (kg)'};
            app.ShearModulusEditField_2Label = uilabel(app.PodInformationTab);
            app.ShearModulusEditField_2Label.Text = 'Shear Modulus';
            app.ShearModulusEditField_2 = uieditfield(app.PodInformationTab, 'numeric');
            app.ShearModulusEditField_2.ValueChangedFcn = createCallbackFcn(app, @ShearModulusEditField_2ValueChanged, true);
            app.ShearModulusEditField_2.Tooltip = {'Modulus of Rigidity of pod shell (gf/mm^2)'};
            app.MomentofInertiaEditFieldLabel = uilabel(app.PodInformationTab);
            app.MomentofInertiaEditFieldLabel.Text = 'Moment of Inertia';
            app.MomentofInertiaEditField = uieditfield(app.PodInformationTab, 'numeric');
            app.MomentofInertiaEditField.ValueChangedFcn = createCallbackFcn(app, @MomentofInertiaEditFieldValueChanged, true);
            app.MomentofInertiaEditField.Tooltip = {'Moment of inertia of pod (kg m^2)'};
            app.YoungsModulusEditField_2Label = uilabel(app.PodInformationTab);
            app.YoungsModulusEditField_2Label.Text = 'Young''s Modulus';
            app.YoungsModulusEditField_2 = uieditfield(app.PodInformationTab, 'numeric');
            app.YoungsModulusEditField_2.ValueChangedFcn = createCallbackFcn(app, @YoungsModulusEditField_2ValueChanged, true);
            app.YoungsModulusEditField_2.Tooltip = {'Young’s Modulus of pod shell (gf/mm^2)'};
            app.DiameterEditFieldLabel = uilabel(app.PodInformationTab);
            app.DiameterEditFieldLabel.Text = 'Diameter';
            app.DiameterEditField = uieditfield(app.PodInformationTab, 'numeric');
            app.DiameterEditField.ValueChangedFcn = createCallbackFcn(app, @DiameterEditFieldValueChanged, true);
            app.DiameterEditField.Tooltip = {'Diameter of pod (m)'};
            app.HeatCapacityEditFieldLabel = uilabel(app.PodInformationTab);
            app.HeatCapacityEditFieldLabel.Text = 'Heat Capacity';
            app.HeatCapacityEditField = uieditfield(app.PodInformationTab, 'numeric');
            app.HeatCapacityEditField.ValueChangedFcn = createCallbackFcn(app, @HeatCapacityEditFieldValueChanged, true);
            app.HeatCapacityEditField.Tooltip = {'Heat capacity of pod (J/kgK)'};
            app.HeatRatioEditFieldLabel = uilabel(app.PodInformationTab);
            app.HeatRatioEditFieldLabel.Text = 'Heat Ratio';
            app.HeatRatioEditField = uieditfield(app.PodInformationTab, 'numeric');
            app.HeatRatioEditField.ValueChangedFcn = createCallbackFcn(app, @HeatRatioEditFieldValueChanged, true);
            app.HeatRatioEditField.Tooltip = {'Percentage of lost kinetic energy transferred into heat'};
            app.DensityEditFieldLabel = uilabel(app.PodInformationTab);
            app.DensityEditFieldLabel.Text = 'Density';
            app.DensityEditField = uieditfield(app.PodInformationTab, 'numeric');
            app.DensityEditField.ValueChangedFcn = createCallbackFcn(app, @DensityEditFieldValueChanged, true);
            app.DensityEditField.Tooltip = {'Density of overall pod and contents (kg/cm^3)'};
            app.MaxServiceTemperatureEditFieldLabel = uilabel(app.PodInformationTab);
            app.MaxServiceTemperatureEditFieldLabel.Text = 'Max. Service Temperature';
            app.MaxServiceTemperatureEditField = uieditfield(app.PodInformationTab, 'numeric');
            app.MaxServiceTemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @MaxServiceTemperatureEditFieldValueChanged, true);
            app.MaxServiceTemperatureEditField.Tooltip = {'Maximum service temperature of pod shell (K)'};
            app.MaxTemperatureDifferentialEditFieldLabel = uilabel(app.PodInformationTab);
            app.MaxTemperatureDifferentialEditFieldLabel.Text = 'Max. Temperature Differential';
            app.MaxTemperatureDifferentialEditField = uieditfield(app.PodInformationTab, 'numeric');
            app.MaxTemperatureDifferentialEditField.ValueChangedFcn = createCallbackFcn(app, @MaxTemperatureDifferentialEditFieldValueChanged, true);
            app.MaxTemperatureDifferentialEditField.Tooltip = {'Maximum rate of temperature change of pod shell (K/s)'};
            app.MaxDeformationEditFieldLabel = uilabel(app.PodInformationTab);
            app.MaxDeformationEditFieldLabel.Text = 'Max. Deformation';
            app.MaxDeformationEditField = uieditfield(app.PodInformationTab, 'numeric');
            app.MaxDeformationEditField.ValueChangedFcn = createCallbackFcn(app, @MaxDeformationEditFieldValueChanged, true);
            app.MaxDeformationEditField.Tooltip = {'Maximum deformation of thickness of pod shell inward (m)'};
            app.YieldStrengthEditFieldLabel = uilabel(app.PodInformationTab);
            app.YieldStrengthEditFieldLabel.Text = 'Yield Strength';
            app.YieldStrengthEditField = uieditfield(app.PodInformationTab, 'numeric');
            app.YieldStrengthEditField.ValueChangedFcn = createCallbackFcn(app, @YieldStrengthEditFieldValueChanged, true);
            app.YieldStrengthEditField.Tooltip = {'Elastic limit of pod shell (Pa)'};
            app.FractureToughnessEditFieldLabel = uilabel(app.PodInformationTab);
            app.FractureToughnessEditFieldLabel.Text = 'Fracture Toughness';
            app.FractureToughnessEditField = uieditfield(app.PodInformationTab, 'numeric');
            app.FractureToughnessEditField.ValueChangedFcn = createCallbackFcn(app, @FractureToughnessEditFieldValueChanged, true);
            app.FractureToughnessEditField.Tooltip = {'Fracture toughness of pod shell (MPa m^0.5)'};
            app.MaxForceEditFieldLabel = uilabel(app.PodInformationTab);
            app.MaxForceEditFieldLabel.Text = 'Max. Force';
            app.MaxForceEditField = uieditfield(app.PodInformationTab, 'numeric');
            app.MaxForceEditField.ValueChangedFcn = createCallbackFcn(app, @MaxForceEditFieldValueChanged, true);
            app.MaxForceEditField.Tooltip = {'Maximum force withstood by pod shell (N/m^2)'};
            app.TensileStrengthEditFieldLabel = uilabel(app.PodInformationTab);
            app.TensileStrengthEditFieldLabel.Text = 'Tensile Strength';
            app.TensileStrengthEditField = uieditfield(app.PodInformationTab, 'numeric');
            app.TensileStrengthEditField.ValueChangedFcn = createCallbackFcn(app, @TensileStrengthEditFieldValueChanged, true);
            app.TensileStrengthEditField.Tooltip = {'Tensile strength of pod shell (Pa)'};
            app.MaxForcecEditFieldLabel = uilabel(app.PodInformationTab);
            app.MaxForcecEditFieldLabel.Text = 'Max. Force (c)';
            app.MaxForcecEditField = uieditfield(app.PodInformationTab, 'numeric');
            app.MaxForcecEditField.ValueChangedFcn = createCallbackFcn(app, @MaxForcecEditFieldValueChanged, true);
            app.MaxForcecEditField.Tooltip = {'Maximum force withstood by pod contents (N/m^2)'};
            app.MaxServiceTemperaturecEditFieldLabel = uilabel(app.PodInformationTab);
            app.MaxServiceTemperaturecEditFieldLabel.Text = 'Max. Service Temperature (c)';
            app.MaxServiceTemperaturecEditField = uieditfield(app.PodInformationTab, 'numeric');
            app.MaxServiceTemperaturecEditField.ValueChangedFcn = createCallbackFcn(app, @MaxServiceTemperaturecEditFieldValueChanged, true);
            app.MaxServiceTemperaturecEditField.Tooltip = {'Maximum service temperature of pod contents (K)'};
            app.MaxAngularAccelerationcEditFieldLabel = uilabel(app.PodInformationTab);
            app.MaxAngularAccelerationcEditFieldLabel.Text = 'Max. Angular Acceleration (c)';
            app.MaxAngularAccelerationcEditField = uieditfield(app.PodInformationTab, 'numeric');
            app.MaxAngularAccelerationcEditField.ValueChangedFcn = createCallbackFcn(app, @MaxAngularAccelerationcEditFieldValueChanged, true);
            app.MaxAngularAccelerationcEditField.Tooltip = {'Maximum angular acceleration of contents (rad/s)'};
            app.StiffnessEditFieldLabel = uilabel(app.PodInformationTab);
            app.StiffnessEditFieldLabel.Text = 'Stiffness';
            app.StiffnessEditField = uieditfield(app.PodInformationTab, 'numeric');
            app.StiffnessEditField.ValueChangedFcn = createCallbackFcn(app, @StiffnessEditFieldValueChanged, true);
            app.StiffnessEditField.Tooltip = {'Stiffness of pod shell (N/m^1.5)'};
            app.COFEditFieldLabel = uilabel(app.PodInformationTab);
            app.COFEditFieldLabel.Text = 'C.O.F.';
            app.COFEditField = uieditfield(app.PodInformationTab, 'text');
            app.COFEditField.ValueChangedFcn = createCallbackFcn(app, @COFEditFieldValueChanged, true);
            app.COFEditField.Tooltip = {'Function defining Coefficient of Friction between pod and position (x, y, L(x,y))'};
            app.COFEditField.Value = '0*x + 0*y';
            app.RollingCOFEditFieldLabel = uilabel(app.PodInformationTab);
            app.RollingCOFEditFieldLabel.Text = 'Rolling C.O.F.';
            app.RollingCOFEditField = uieditfield(app.PodInformationTab, 'text');
            app.RollingCOFEditField.ValueChangedFcn = createCallbackFcn(app, @RollingCOFEditFieldValueChanged, true);
            app.RollingCOFEditField.Tooltip = {'Function defining Rolling Coefficient of Friction between pod and position (x, y, L(x,y))'};
            app.RollingCOFEditField.Value = '0*x + 0*y';
            app.COREditFieldLabel = uilabel(app.PodInformationTab);
            app.COREditFieldLabel.Text = 'C.O.R.';
            app.COREditField = uieditfield(app.PodInformationTab, 'text');
            app.COREditField.ValueChangedFcn = createCallbackFcn(app, @COREditFieldValueChanged, true);
            app.COREditField.Tooltip = {'Function defining Coefficient of Restitution between pod and position (x, y, L(x,y))'};
            app.COREditField.Value = '0*x + 0*y';
            app.ThermalConductivityEditFieldLabel = uilabel(app.PodInformationTab);
            app.ThermalConductivityEditFieldLabel.Text = 'Thermal Conductivity';
            app.ThermalConductivityEditField = uieditfield(app.PodInformationTab, 'text');
            app.ThermalConductivityEditField.ValueChangedFcn = createCallbackFcn(app, @ThermalConductivityEditFieldValueChanged, true);
            app.ThermalConductivityEditField.Tooltip = {'Function defining thermal conductivity between pod and position (W/mK)'};
            app.ThermalConductivityEditField.Value = '0*x + 0*y';
            app.InitialConditionsTab = uitab(app.TabGroup);
            app.InitialConditionsTab.Title = 'Initial Conditions';
            app.Image_5 = uiimage(app.InitialConditionsTab);
            app.Image_5.ImageSource = 'IRIS_Black.png';
            app.InitialConditionsLabel = uilabel(app.InitialConditionsTab);
            app.InitialConditionsLabel.Text = 'Initial Conditions';
            app.yVelocityComponentEditFieldLabel = uilabel(app.InitialConditionsTab);
            app.yVelocityComponentEditFieldLabel.Text = 'y Velocity Component';
            app.yVelocityComponentEditField = uieditfield(app.InitialConditionsTab, 'numeric');
            app.yVelocityComponentEditField.ValueChangedFcn = createCallbackFcn(app, @yVelocityComponentEditFieldValueChanged, true);
            app.yVelocityComponentEditField.Tooltip = {'y component of vector defining initial velocity of pod (m/s)'};
            app.iSpinComponentEditFieldLabel = uilabel(app.InitialConditionsTab);
            app.iSpinComponentEditFieldLabel.Text = 'i Spin Component';
            app.iSpinComponentEditField = uieditfield(app.InitialConditionsTab, 'numeric');
            app.iSpinComponentEditField.ValueChangedFcn = createCallbackFcn(app, @iSpinComponentEditFieldValueChanged, true);
            app.iSpinComponentEditField.Tooltip = {'i component of vector defining initial body rates of pod (rad/s)'};
            app.jSpinComponentEditFieldLabel = uilabel(app.InitialConditionsTab);
            app.jSpinComponentEditFieldLabel.Text = 'j Spin Component';
            app.jSpinComponentEditField = uieditfield(app.InitialConditionsTab, 'numeric');
            app.jSpinComponentEditField.ValueChangedFcn = createCallbackFcn(app, @jSpinComponentEditFieldValueChanged, true);
            app.jSpinComponentEditField.Tooltip = {'j component of vector defining initial body rates of pod (rad/s)'};
            app.yLaunchCoordinateEditFieldLabel = uilabel(app.InitialConditionsTab);
            app.yLaunchCoordinateEditFieldLabel.Text = 'y Launch Coordinate';
            app.yLaunchCoordinateEditField = uieditfield(app.InitialConditionsTab, 'numeric');
            app.yLaunchCoordinateEditField.ValueChangedFcn = createCallbackFcn(app, @yLaunchCoordinateEditFieldValueChanged, true);
            app.yLaunchCoordinateEditField.Tooltip = {'y component of coordinates defining initial location of pod (m)'};
            app.zLaunchCoordinateEditFieldLabel = uilabel(app.InitialConditionsTab);
            app.zLaunchCoordinateEditFieldLabel.Text = 'z Launch Coordinate';
            app.zLaunchCoordinateEditField = uieditfield(app.InitialConditionsTab, 'numeric');
            app.zLaunchCoordinateEditField.ValueChangedFcn = createCallbackFcn(app, @zLaunchCoordinateEditFieldValueChanged, true);
            app.zLaunchCoordinateEditField.Tooltip = {'z component of coordinates defining initial location of pod (m)'};
            app.kSpinComponentEditFieldLabel = uilabel(app.InitialConditionsTab);
            app.kSpinComponentEditFieldLabel.Text = 'k Spin Component';
            app.kSpinComponentEditField = uieditfield(app.InitialConditionsTab, 'numeric');
            app.kSpinComponentEditField.ValueChangedFcn = createCallbackFcn(app, @kSpinComponentEditFieldValueChanged, true);
            app.kSpinComponentEditField.Tooltip = {'k component of vector defining initial body rates of pod (rad/s)'};
            app.PodTemperatureEditFieldLabel = uilabel(app.InitialConditionsTab);
            app.PodTemperatureEditFieldLabel.Text = 'Pod Temperature';
            app.PodTemperatureEditField = uieditfield(app.InitialConditionsTab, 'numeric');
            app.PodTemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @PodTemperatureEditFieldValueChanged, true);
            app.PodTemperatureEditField.Tooltip = {'Initial temperature of pod (K)'};
            app.xVelocityComponentEditFieldLabel = uilabel(app.InitialConditionsTab);
            app.xVelocityComponentEditFieldLabel.Text = 'x Velocity Component';
            app.xVelocityComponentEditField = uieditfield(app.InitialConditionsTab, 'numeric');
            app.xVelocityComponentEditField.ValueChangedFcn = createCallbackFcn(app, @xVelocityComponentEditFieldValueChanged, true);
            app.xVelocityComponentEditField.Tooltip = {'x component of vector defining initial velocity of pod (m/s)'};
            app.xLaunchCoordinateEditFieldLabel = uilabel(app.InitialConditionsTab);
            app.xLaunchCoordinateEditFieldLabel.Text = 'x Launch Coordinate';
            app.xLaunchCoordinateEditField = uieditfield(app.InitialConditionsTab, 'numeric');
            app.xLaunchCoordinateEditField.ValueChangedFcn = createCallbackFcn(app, @xLaunchCoordinateEditFieldValueChanged, true);
            app.xLaunchCoordinateEditField.Tooltip = {'x component of coordinates defining initial location of pod (m)'};
            app.zVelocityComponentEditField = uieditfield(app.InitialConditionsTab, 'numeric');
            app.zVelocityComponentEditField.ValueChangedFcn = createCallbackFcn(app, @zVelocityComponentEditFieldValueChanged, true);
            app.zVelocityComponentEditField.Tooltip = {'z component of vector defining initial velocity of pod (m/s)'};
            app.zVelocityComponentEditFieldLabel = uilabel(app.InitialConditionsTab);
            app.zVelocityComponentEditFieldLabel.Text = 'z Velocity Component';
            app.BackButton_5 = uibutton(app.InitialConditionsTab, 'push');
            app.BackButton_5.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton_5.Text = 'Back';
            % Show the figure after all components are created
            app.InteractiveResourceImpactSimulationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InteractiveResourceImpactSimulationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InteractiveResourceImpactSimulationUIFigure)
        end
    end
end