classdef IRIS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InteractiveResourceImpactSimulationJoshuaAngeUIFigure  matlab.ui.Figure
        Background                     matlab.ui.control.Image
        INTERACTIVERESOURCEIMPACTSIMULATIONLabel  matlab.ui.control.Label
        VersionNumber                  matlab.ui.control.Label
        Logo                           matlab.ui.control.Image
        GraphicsPanel                  matlab.ui.container.Panel
        Main                           matlab.ui.control.UIAxes
        Second                         matlab.ui.control.UIAxes
        DeformationButton              matlab.ui.control.Button
        ReactionsButton                matlab.ui.control.Button
        SurvivabilityButton            matlab.ui.control.Button
        VelocityButton                 matlab.ui.control.Button
        EnergyButton                   matlab.ui.control.Button
        SpinButton                     matlab.ui.control.Button
        TemperatureButton              matlab.ui.control.Button
        LogPanel                       matlab.ui.container.Panel
        RunSimulationButton            matlab.ui.control.Button
        ExportDataButton               matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        Lamp                           matlab.ui.control.Lamp
        ParameterPanel                 matlab.ui.container.Panel
        ParameterTabGroup              matlab.ui.container.TabGroup
        InitialConditionsTab           matlab.ui.container.Tab
        xLaunchCoordinateEditFieldLabel  matlab.ui.control.Label
        xLaunchCoordinateEditField     matlab.ui.control.EditField
        yLaunchCoordinateEditFieldLabel  matlab.ui.control.Label
        yLaunchCoordinateEditField     matlab.ui.control.EditField
        zLaunchCoordinateEditFieldLabel  matlab.ui.control.Label
        zLaunchCoordinateEditField     matlab.ui.control.EditField
        xVelocityComponentEditFieldLabel  matlab.ui.control.Label
        xVelocityComponentEditField    matlab.ui.control.EditField
        yVelocityComponentEditFieldLabel  matlab.ui.control.Label
        yVelocityComponentEditField    matlab.ui.control.EditField
        zVelocityComponentEditFieldLabel  matlab.ui.control.Label
        zVelocityComponentEditField    matlab.ui.control.EditField
        iSpinComponentEditFieldLabel   matlab.ui.control.Label
        iSpinComponentEditField        matlab.ui.control.EditField
        jSpinComponentEditFieldLabel   matlab.ui.control.Label
        jSpinComponentEditField        matlab.ui.control.EditField
        kSpinComponentEditFieldLabel   matlab.ui.control.Label
        kSpinComponentEditField        matlab.ui.control.EditField
        PodTemperatureEditFieldLabel   matlab.ui.control.Label
        PodTemperatureEditField        matlab.ui.control.EditField
        PodInformationTab              matlab.ui.container.Tab
        TotalMassEditFieldLabel        matlab.ui.control.Label
        TotalMassEditField             matlab.ui.control.EditField
        ShearModulusEditField_2Label   matlab.ui.control.Label
        ShearModulusEditField_2        matlab.ui.control.EditField
        YoungsModulusEditField_2Label  matlab.ui.control.Label
        YoungsModulusEditField_2       matlab.ui.control.EditField
        MomentofInertiaEditFieldLabel  matlab.ui.control.Label
        MomentofInertiaEditField       matlab.ui.control.EditField
        DiameterEditFieldLabel         matlab.ui.control.Label
        DiameterEditField              matlab.ui.control.EditField
        HeatCapacityEditFieldLabel     matlab.ui.control.Label
        HeatCapacityEditField          matlab.ui.control.EditField
        DensityEditFieldLabel          matlab.ui.control.Label
        DensityEditField               matlab.ui.control.EditField
        MaximumServiceTemperatureEditFieldLabel  matlab.ui.control.Label
        MaximumServiceTemperatureEditField  matlab.ui.control.EditField
        MaximumTemperatureDifferentialEditFieldLabel  matlab.ui.control.Label
        MaximumTemperatureDifferentialEditField  matlab.ui.control.EditField
        MaximumDeformationEditFieldLabel  matlab.ui.control.Label
        MaximumDeformationEditField    matlab.ui.control.EditField
        YieldStrengthEditFieldLabel    matlab.ui.control.Label
        YieldStrengthEditField         matlab.ui.control.EditField
        FractureToughnessEditFieldLabel  matlab.ui.control.Label
        FractureToughnessEditField     matlab.ui.control.EditField
        MaximumForceEditFieldLabel     matlab.ui.control.Label
        MaximumForceEditField          matlab.ui.control.EditField
        TensileStrengthEditFieldLabel  matlab.ui.control.Label
        TensileStrengthEditField       matlab.ui.control.EditField
        MaximumForcecEditFieldLabel    matlab.ui.control.Label
        MaximumForcecEditField         matlab.ui.control.EditField
        MaximumServiceTemperaturecEditFieldLabel  matlab.ui.control.Label
        MaximumServiceTemperaturecEditField  matlab.ui.control.EditField
        MaximumAngularAccelerationcEditFieldLabel  matlab.ui.control.Label
        MaximumAngularAccelerationcEditField  matlab.ui.control.EditField
        StiffnessEditFieldLabel        matlab.ui.control.Label
        StiffnessEditField             matlab.ui.control.EditField
        EnvironmentalConditionsTab     matlab.ui.container.Tab
        AtmosphericDensityEditFieldLabel  matlab.ui.control.Label
        AtmosphericDensityEditField    matlab.ui.control.EditField
        GravitationalAccelerationEditFieldLabel  matlab.ui.control.Label
        GravitationalAccelerationEditField  matlab.ui.control.EditField
        ShearModulusEditFieldLabel     matlab.ui.control.Label
        ShearModulusEditField          matlab.ui.control.EditField
        YoungsModulusEditFieldLabel    matlab.ui.control.Label
        YoungsModulusEditField         matlab.ui.control.EditField
        SurfaceTemperatureEditFieldLabel  matlab.ui.control.Label
        SurfaceTemperatureEditField    matlab.ui.control.EditField
        CoefficientofFrictionpEditFieldLabel  matlab.ui.control.Label
        CoefficientofFrictionpEditField  matlab.ui.control.EditField
        RollingCoefficientofFrictionpEditFieldLabel  matlab.ui.control.Label
        RollingCoefficientofFrictionpEditField  matlab.ui.control.EditField
        CoefficientofRestitutionpEditFieldLabel  matlab.ui.control.Label
        CoefficientofRestitutionpEditField  matlab.ui.control.EditField
        ThermalConductivitypEditFieldLabel  matlab.ui.control.Label
        ThermalConductivitypEditField  matlab.ui.control.EditField
        TerrainHeightEditFieldLabel    matlab.ui.control.Label
        TerrainHeightEditField         matlab.ui.control.EditField
        TerrainWidthEditFieldLabel     matlab.ui.control.Label
        TerrainWidthEditField          matlab.ui.control.EditField
        Image                          matlab.ui.control.Image
        SurfacepngLabel                matlab.ui.control.Label
        ProgramDataTab                 matlab.ui.container.Tab
        MinimumPerpendicularMagnitudeEditFieldLabel  matlab.ui.control.Label
        MinimumPerpendicularMagnitudeEditField  matlab.ui.control.EditField
        TimeSegmentLengthEditFieldLabel  matlab.ui.control.Label
        TimeSegmentLengthEditField     matlab.ui.control.EditField
        TerrainGraphIntervalEditFieldLabel  matlab.ui.control.Label
        TerrainGraphIntervalEditField  matlab.ui.control.EditField
        TerrainGraphMaximumEditFieldLabel  matlab.ui.control.Label
        TerrainGraphMaximumEditField   matlab.ui.control.EditField
        TerrainGraphMinimumEditFieldLabel  matlab.ui.control.Label
        TerrainGraphMinimumEditField   matlab.ui.control.EditField
        TimeGraphIntervalEditFieldLabel  matlab.ui.control.Label
        TimeGraphIntervalEditField     matlab.ui.control.EditField
        MaximumiIterationsEditFieldLabel  matlab.ui.control.Label
        MaximumiIterationsEditField    matlab.ui.control.EditField
        MaximumjIterationsEditFieldLabel  matlab.ui.control.Label
        MaximumjIterationsEditField    matlab.ui.control.EditField
        MinimumEdgeDistanceEditFieldLabel  matlab.ui.control.Label
        MinimumEdgeDistanceEditField   matlab.ui.control.EditField
        MinimumSurfaceDistanceEditFieldLabel  matlab.ui.control.Label
        MinimumSurfaceDistanceEditField  matlab.ui.control.EditField
        MinimumVelocityMagnitudeEditFieldLabel  matlab.ui.control.Label
        MinimumVelocityMagnitudeEditField  matlab.ui.control.EditField
        MinimumCollisionDistanceEditFieldLabel  matlab.ui.control.Label
        MinimumCollisionDistanceEditField  matlab.ui.control.EditField
        MinimumTimeofFlightEditFieldLabel  matlab.ui.control.Label
        MinimumTimeofFlightEditField   matlab.ui.control.EditField
        MaximumTimeofFlightEditFieldLabel  matlab.ui.control.Label
        MaximumTimeofFlightEditField   matlab.ui.control.EditField
        RangeofPerpendicularMagEditFieldLabel  matlab.ui.control.Label
        RangeofPerpendicularMagEditField  matlab.ui.control.EditField
        TimeSteppingIntervalEditFieldLabel  matlab.ui.control.Label
        TimeSteppingIntervalEditField  matlab.ui.control.EditField
        BicubicInterpolationIntervalEditFieldLabel  matlab.ui.control.Label
        BicubicInterpolationIntervalEditField  matlab.ui.control.EditField
    end

    
    properties (Access = public)
        folder = pwd;
        file = 'PTHeight_CraterTest';
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ButtonPushed(app, event)
            load(strcat(app.folder,'\data\outputs\',app.file,'.mat'));
            
            hold (app.Main,"on");
            grid (app.Main,"minor");
            view (app.Main,3);
            colormap(app.Main,"gray")
            xlim(app.Main,[0 L_domain]);
            ylim(app.Main,[0 L_domain]);
            zlim(app.Main,[0 L_range]);
            surfc(app.Main,L_x, L_y, L_z,'EdgeAlpha',0.1,'FaceAlpha',1,'DisplayName','Surface Topology') % Mesh Plot
            
             for iit = 1:i_max
                 if isempty(b{iit}) == 0
                    fplot3(app.Main,b{iit}.x_i,b{iit}.y_i,b{iit}.z_i,[0 double(b{iit}.t_f_i)],'DisplayName','Trajectory','lineWidth',1,'Color','black');
                 end
                 for jit = 1:j_max
                     if isempty(s{iit,jit}) == 0
                        disp(jit);
                        quiver3(app.Main,s{iit,jit}.O_ij(1,1),s{iit,jit}.O_ij(1,2),s{iit,jit}.O_ij(1,3),s{iit,jit}.O_ij(2,1),s{iit,jit}.O_ij(2,2),s{iit,jit}.O_ij(2,3),'lineWidth',1,'Color','black','MaxHeadSize',0.5,'DisplayName','Traced Path Segment');
                     end
                 end
             end
             hold (app.Main,"off");
        end

        % Button pushed function: TemperatureButton
        function TemperatureButtonPushed(app, event)
            legend(app.Second,"off");
            cla(app.Second,'reset');
            load(strcat(app.folder,'\data\outputs\',app.file,'.mat'));
            
            timeA = 0;
            for iit = 1:i_max
                if isempty(b{iit}) == 0
                    for iteration = 1:floor(double(b{iit}.t_f_i)/T_units)
                        multitude = iteration * T_units;
                        plot(app.Second,timeA+multitude,b{iit}.T_i,'o','Color','blue','HandleVisibility','off');
                    end
                    timeA = timeA + b{iit}.t_f_i;
                end
                for jit = 1:j_max
                    if isempty(s{iit,jit}) == 0
                        plot(app.Second,timeA,s{iit,jit}.T_s_ij,'^','Color','blue','HandleVisibility','off');
                        timeA = timeA + T;
                        plot(app.Second,timeA,s{iit,jit}.T_f_ij,'^','Color','blue','HandleVisibility','off');
                    end
                end
            end
            
            grid (app.Second,"minor");
            xlim(app.Second,[0, double(timeA+T)]);
            xlabel(app.Second,'Time elapsed (s)');
            ylabel(app.Second,'Temperature (K)');
            title(app.Second,'Temperature');
            disp("Temperature Graph");
        end

        % Button pushed function: DeformationButton
        function DeformationButtonPushed(app, event)
            legend(app.Second,"off");
            cla(app.Second,'reset');
            load(strcat(app.folder,'\data\outputs\',app.file,'.mat'));
            
            timeA = 0;
            plot(app.Second,0,0,'^','Color','blue','HandleVisibility','on','DisplayName',"Elastic Compression");
            for iit = 1:i_max
                if isempty(b{iit}) == 0
                    timeA = timeA + b{iit}.t_f_i;
                end
                for jit = 1:j_max
                    if isempty(s{iit,jit}) == 0
                        timeA = timeA + T;
                        plot(app.Second,(timeA),vpa(s{iit,jit}.D_ij),'^','Color','blue','HandleVisibility','off');
                    end
                end
            end
            
            grid (app.Second,"minor");
            xlim(app.Second,[0, double(timeA+T)]);
            xlabel(app.Second,'Time elapsed (s)');
            ylabel(app.Second,'Elastic Compression (m)');
            title(app.Second,'Deformation');
            disp("Deformation Graph");
        end

        % Button pushed function: EnergyButton
        function EnergyButtonPushed(app, event)
            legend(app.Second,"off");
            cla(app.Second,'reset');
            load(strcat(app.folder,'\data\outputs\',app.file,'.mat'));
            
            timeA = 0;
            a = plot(app.Second,0,double(b{1}.KE_s_i),'d','Color','blue','HandleVisibility','on','DisplayName',"Kinetic Energy");
            b = plot(app.Second,0,0,'o','Color','red','HandleVisibility','on','DisplayName',"Potential Energy");
            c = plot(app.Second,0,0,'s','Color','black','HandleVisibility','on','DisplayName',"Total Energy");
            for iit = 1:i_max
                if isempty(b{iit}) == 0
                    for iteration = 1:floor(b{iit}.t_f_i/T_units)
                        multitude = iteration * T_units;
                        plot(app.Second,double(timeA+multitude),double(0.5*m*mag([b{iit}.x_i(multitude) b{iit}.y_i(multitude) b{iit}.z_i(multitude); b{iit}.dx_i(multitude) b{iit}.dy_i(multitude) b{iit}.dz_i(multitude)])^2),'d','Color','blue','HandleVisibility','off');  
                        plot(app.Second,double(timeA+multitude),m*g*(b{iit}.z_i(multitude)-L(b{iit}.x_i(multitude),b{iit}.y_i(multitude))),'o','Color','red','HandleVisibility','off');
                        plot(app.Second,double(timeA+multitude),double(0.5*m*mag([b{iit}.x_i(multitude) b{iit}.y_i(multitude) b{iit}.z_i(multitude); b{iit}.dx_i(multitude) b{iit}.dy_i(multitude) b{iit}.dz_i(multitude)])^2)+m*g*(b{iit}.z_i(multitude)-L(b{iit}.x_i(multitude),b{iit}.y_i(multitude))),'s','Color','black','HandleVisibility','off');
                    end
                    timeA = timeA + b{iit}.t_f_i;
                    plot(app.Second,timeA,b{iit}.KE_f_i,'d','Color','blue','HandleVisibility','off');
                    plot(app.Second,timeA,0,'o','Color','red','HandleVisibility','off');    
                    plot(app.Second,timeA,b{iit}.KE_f_i,'s','Color','black','HandleVisibility','off');  
                end
                for jit = 1:j_max
                    if isempty(s{iit,jit}) == 0
                        plot(app.Second,(timeA),double(s{iit,jit}.KE_s_ij),'d','Color','blue','HandleVisibility','off');
                        timeA = timeA + T;
                        plot(app.Second,(timeA),double(s{iit,jit}.KE_f_ij),'d','Color','blue','HandleVisibility','off');
                        plot(app.Second,(timeA),0,'o','Color','red','HandleVisibility','off');    
                        plot(app.Second,(timeA),double(s{iit,jit}.KE_f_ij),'s','Color','black','HandleVisibility','off');
                    end
                end
            end
            
            grid (app.Second,"minor");
            xlim(app.Second,[double(b{1}.t_f_i), double(timeA+T)]);
            legend(app.Second,[a b c],{'Kinetic Energy' 'Potential Energy' 'Total Energy'});
            xlabel(app.Second,'Time elapsed (s)');
            ylabel(app.Second,'Energy (J)');
            title(app.Second,'Kinetic Energy');
            disp("Energy Graph");
        end

        % Button pushed function: ReactionsButton
        function ReactionsButtonPushed(app, event)
            legend(app.Second,"off");
            cla(app.Second,'reset');
            load(strcat(app.folder,'\data\outputs\',app.file,'.mat'));
            
            timeA = 0;
            d = plot(app.Second,0,0,'o','Color','green','DisplayName',"Initial Velocity");
            e = plot(app.Second,0,0,'^','Color','black','DisplayName',"Rotational");
            f = plot(app.Second,0,0,'^','Color','green','DisplayName',"Normal");
            g = plot(app.Second,0,0,'s','Color','blue','DisplayName',"Friction");
            h = plot(app.Second,0,0,'s','Color','green','DisplayName',"Gravity");
            i = plot(app.Second,0,0,'^','Color','red','DisplayName',"Elastic");
            j = plot(app.Second,0,0,'o','Color','red','DisplayName',"Final Velocity");
            for iit = 1:i_max
                if isempty(b{iit}) == 0
                    timeA = timeA + b{iit}.t_f_i;
                end
                for jit = 1:j_max
                    if isempty(s{iit,jit}) == 0
                        timeA = timeA + T;
                        plot(app.Second,timeA,mag(s{iit,jit}.v_ij),'o','Color','green','HandleVisibility','off');
                        plot(app.Second,timeA,mag(s{iit,jit}.A_r_ij),'^','Color','black','HandleVisibility','off');
                        plot(app.Second,timeA,mag(s{iit,jit}.A_n_ij),'^','Color','green','HandleVisibility','off');
                        plot(app.Second,timeA,mag(s{iit,jit}.A_f_ij),'s','Color','blue','HandleVisibility','off');
                        plot(app.Second,timeA,mag(s{iit,jit}.A_g_ij),'s','Color','green','HandleVisibility','off');
                        plot(app.Second,timeA,mag(s{iit,jit}.A_e_ij),'^','Color','red','HandleVisibility','off');
                        plot(app.Second,timeA,mag(s{iit,jit}.V_ij),'o','Color','red','HandleVisibility','off');
                    end
                end
            end
            
            grid (app.Second,"minor");
            xlim(app.Second,[0, double(timeA+T)]);
            ylim(app.Second,[0, 100]);
            xlabel(app.Second,'Time elapsed (s)');
            ylabel(app.Second,'Velocity Influence (m/s)');
            title(app.Second,'Reactionary Forces');
            legend(app.Second,d, e, f, g, h, i, j,'Initial Velocity', 'Rotational', 'Normal', 'Friction', 'Gravity', 'Elastic', 'Final Velocity');
            disp("Reaction Graph");
        end

        % Button pushed function: SpinButton
        function SpinButtonPushed(app, event)
            legend(app.Second,"off");
            cla(app.Second,'reset');
            load(strcat(app.folder,'\data\outputs\',app.file,'.mat'));
            
            timeA = 0;
            k = plot(app.Second,0,q_i(2,1),'o','Color','blue','DisplayName',"X");
            l = plot(app.Second,0,q_i(2,2),'^','Color','green','DisplayName',"Y");
            m = plot(app.Second,0,q_i(2,3),'s','Color','red','DisplayName',"Z");
            n = plot(app.Second,0,mag(u_i),'+','Color','magenta','DisplayName',"Total");
            o = plot(app.Second,0,0,'o','Color','black','DisplayName',"X Influence");
            p = plot(app.Second,0,0,'^','Color','black','DisplayName',"Y Influence");
            q = plot(app.Second,0,0,'s','Color','black','DisplayName',"Z Influence");
            r = plot(app.Second,0,0,'+','Color','black','DisplayName',"Total Influence");
            for iit = 1:i_max
                if isempty(b{iit}) == 0
                    for iteration = 1:floor(double(b{iit}.t_f_i)/T_units)
                        multitude = iteration * T_units;
                        plot(app.Second,timeA+multitude,b{iit}.q_i(2,1),'o','Color','blue','HandleVisibility','off');
                        plot(app.Second,timeA+multitude,b{iit}.q_i(2,2),'^','Color','green','HandleVisibility','off');
                        plot(app.Second,timeA+multitude,b{iit}.q_i(2,3),'s','Color','red','HandleVisibility','off');
                        plot(app.Second,timeA+multitude,mag(b{iit}.q_i),'+','Color','magenta','HandleVisibility','off');
                    end
                    timeA = timeA + b{iit}.t_f_i;
                    plot(app.Second,timeA,b{iit}.q_i(2,1),'o','Color','blue','HandleVisibility','off');
                    plot(app.Second,timeA,b{iit}.q_i(2,2),'^','Color','green','HandleVisibility','off');
                    plot(app.Second,timeA,b{iit}.q_i(2,3),'s','Color','red','HandleVisibility','off');
                    plot(app.Second,timeA,mag(b{iit}.q_i),'+','Color','magenta','HandleVisibility','off');
                end
                for jit = 1:j_max
                    if isempty(s{iit,jit}) == 0
                        plot(app.Second,timeA,s{iit,jit}.q_ij(2,1),'o','Color','blue','HandleVisibility','off');
                        plot(app.Second,timeA,s{iit,jit}.q_ij(2,2),'^','Color','green','HandleVisibility','off');
                        plot(app.Second,timeA,s{iit,jit}.q_ij(2,3),'s','Color','red','HandleVisibility','off');
                        plot(app.Second,timeA,mag(s{iit,jit}.q_ij),'+','Color','magenta','HandleVisibility','off');
                        timeA = timeA + 0.5*T;
                        plot(app.Second,timeA,s{iit,jit}.p_ij(2,1),'o','Color','black','HandleVisibility','off');
                        plot(app.Second,timeA,s{iit,jit}.p_ij(2,2),'^','Color','black','HandleVisibility','off');
                        plot(app.Second,timeA,s{iit,jit}.p_ij(2,3),'s','Color','black','HandleVisibility','off');
                        plot(app.Second,timeA,mag(s{iit,jit}.p_ij),'+','Color','black','HandleVisibility','off');
                        timeA = timeA + 0.5*T;
                        plot(app.Second,timeA,s{iit,jit}.Q_ij(2,1),'o','Color','blue','HandleVisibility','off');
                        plot(app.Second,timeA,s{iit,jit}.Q_ij(2,1),'^','Color','green','HandleVisibility','off');
                        plot(app.Second,timeA,s{iit,jit}.Q_ij(2,1),'s','Color','red','HandleVisibility','off');
                        plot(app.Second,timeA,mag(s{iit,jit}.Q_ij),'+','Color','magenta','HandleVisibility','off');
                    end
                end
            end
            
            grid (app.Second,"minor");
            xlim(app.Second,[0, double(timeA+T)]);
            xlabel(app.Second,'Time elapsed (s)');
            ylabel(app.Second,'Velocity (rad/s)');
            legend(app.Second,[k l m n o p q r],{'X' 'Y' 'Z' 'Total' 'X Influence' 'Y Influence' 'Z Influence' 'Total Influence'});
            title(app.Second,'Spin');
            disp("Spin Graph");
        end

        % Button pushed function: SurvivabilityButton
        function SurvivabilityButtonPushed(app, event)
            legend(app.Second,"off");
            cla(app.Second,'reset');
            load(strcat(app.folder,'\data\outputs\',app.file,'.mat'));
            
            timeA = 0;
            s = plot(app.Second,0,0,'o','Color','blue','DisplayName',"Pod");
            t = plot(app.Second,0,0,'^','Color','red','DisplayName',"Contents");
            for iit = 1:i_max
                if isempty(b{iit}) == 0
                    timeA = timeA + b{iit}.t_f_i;
                end
                for jit = 1:j_max
                    if isempty(s{iit,jit}) == 0
                        timeA = timeA + T;
                        plot(app.Second,timeA,s{iit,jit}.S_pod_ij,'o','Color','blue','HandleVisibility','off');
                        plot(app.Second,timeA,s{iit,jit}.S_contents_ij,'^','Color','red','HandleVisibility','off');
                    end
                end
            end
            
            grid (app.Second,"minor");
            xlim(app.Second,[0, double(timeA+T)]);
            xlabel(app.Second,'Time elapsed (s)');
            ylabel(app.Second,'Survivability Metric');
            legend(app.Second,[s t],{'Pod' 'Contents'});
            title(app.Second,'Survivability');
            disp("Survivability Graph");
        end

        % Button pushed function: VelocityButton
        function VelocityButtonPushed(app, event)
            legend(app.Second,"off");
            cla(app.Second,'reset');
            load(strcat(app.folder,'\data\outputs\',app.file,'.mat'));
            
            timeA = 0;
            u = plot(app.Second,0,u_i(2,1),'o','Color','blue','DisplayName',"X");
            v = plot(app.Second,0,u_i(2,2),'^','Color','green','DisplayName',"Y");
            w = plot(app.Second,0,u_i(2,3),'s','Color','red','DisplayName',"Z");
            x = plot(app.Second,0,mag(u_i),'+','Color','magenta','DisplayName',"Total");
            for iit = 1:i_max
                if isempty(b{iit}) == 0
                    for iteration = 1:floor(double(b{iit}.t_f_i)/T_units)
                        multitude = iteration * T_units;
                        plot(app.Second,timeA+multitude,b{iit}.dx_i(multitude),'o','Color','blue','HandleVisibility','off');
                        plot(app.Second,timeA+multitude,b{iit}.dy_i(multitude),'^','Color','green','HandleVisibility','off');
                        plot(app.Second,timeA+multitude,b{iit}.dz_i(multitude),'s','Color','red','HandleVisibility','off');
                        plot(app.Second,timeA+multitude,mag([0,0,0;b{iit}.dx_i(multitude),b{iit}.dy_i(multitude),b{iit}.dz_i(multitude)]),'+','Color','magenta','HandleVisibility','off');
                    end
                    timeA = timeA + b{iit}.t_f_i;
                    plot(app.Second,timeA,b{iit}.v_i(2,1),'o','Color','blue','HandleVisibility','off');
                    plot(app.Second,timeA,b{iit}.v_i(2,2),'^','Color','green','HandleVisibility','off');
                    plot(app.Second,timeA,b{iit}.v_i(2,3),'s','Color','red','HandleVisibility','off');
                    plot(app.Second,timeA,mag(b{iit}.v_i),'+','Color','magenta','HandleVisibility','off');
                end
                for jit = 1:j_max
                    if isempty(s{iit,jit}) == 0
                        timeA = timeA + 0.5*T;
                        plot(app.Second,timeA,0.5*s{iit,jit}.v_ij(2,1)+0.5*s{iit,jit}.V_ij(2,1),'o','Color','blue','HandleVisibility','off');
                        plot(app.Second,timeA,0.5*s{iit,jit}.v_ij(2,2)+0.5*s{iit,jit}.V_ij(2,2),'^','Color','green','HandleVisibility','off');
                        plot(app.Second,timeA,0.5*s{iit,jit}.v_ij(2,3)+0.5*s{iit,jit}.V_ij(2,3),'s','Color','red','HandleVisibility','off');
                        plot(app.Second,timeA,0.5*mag(s{iit,jit}.v_ij)+0.5*mag(s{iit,jit}.V_ij),'+','Color','magenta','HandleVisibility','off');
                        timeA = timeA + 0.5*T;
                        plot(app.Second,timeA,s{iit,jit}.V_ij(2,1),'o','Color','blue','HandleVisibility','off');
                        plot(app.Second,timeA,s{iit,jit}.V_ij(2,2),'^','Color','green','HandleVisibility','off');
                        plot(app.Second,timeA,s{iit,jit}.V_ij(2,3),'s','Color','red','HandleVisibility','off');
                        plot(app.Second,timeA,mag(s{iit,jit}.V_ij),'+','Color','magenta','HandleVisibility','off');
                    end
                end
            end
            
            grid (app.Second,"minor");
            xlim(app.Second,[0, double(timeA+T)]);
            xlabel(app.Second,'Time elapsed (s)');
            ylabel(app.Second,'Velocity (m/s)');
            %legend(app.Second,[u v w x],{'X' 'Y' 'Z' 'Total'});
            title(app.Second,'Velocity');
            disp("Velocity Graph");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure = uifigure('Visible', 'off');
            app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure.Name = 'Interactive Resource Impact Simulation - Joshua Ange';
            app.Background = uiimage(app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure);
            app.Background.ImageSource = 'Blue_Background.png';
            app.INTERACTIVERESOURCEIMPACTSIMULATIONLabel = uilabel(app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure);
            app.INTERACTIVERESOURCEIMPACTSIMULATIONLabel.Text = 'INTERACTIVE RESOURCE IMPACT SIMULATION';
            app.VersionNumber = uilabel(app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure);
            app.VersionNumber.Text = '1.5.0';
            app.Logo = uiimage(app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure);
            app.Logo.ImageSource = 'IRIS_White.png';
            app.GraphicsPanel = uipanel(app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure);
            app.Main = uiaxes(app.GraphicsPanel);
            title(app.Main, {'Supply Pod Motion'; ''})
            xlabel(app.Main, 'X')
            ylabel(app.Main, 'Y')
            app.Second = uiaxes(app.GraphicsPanel);
            title(app.Second, 'Survivability Metrics')
            xlabel(app.Second, 'X')
            ylabel(app.Second, 'Y')
            app.DeformationButton = uibutton(app.GraphicsPanel, 'push');
            app.DeformationButton.ButtonPushedFcn = createCallbackFcn(app, @DeformationButtonPushed, true);
            app.DeformationButton.Text = 'Deformation';
            app.ReactionsButton = uibutton(app.GraphicsPanel, 'push');
            app.ReactionsButton.ButtonPushedFcn = createCallbackFcn(app, @ReactionsButtonPushed, true);
            app.ReactionsButton.Text = 'Reactions';
            app.SurvivabilityButton = uibutton(app.GraphicsPanel, 'push');
            app.SurvivabilityButton.ButtonPushedFcn = createCallbackFcn(app, @SurvivabilityButtonPushed, true);
            app.SurvivabilityButton.Text = 'Survivability';
            app.VelocityButton = uibutton(app.GraphicsPanel, 'push');
            app.VelocityButton.ButtonPushedFcn = createCallbackFcn(app, @VelocityButtonPushed, true);
            app.VelocityButton.Text = 'Velocity';
            app.EnergyButton = uibutton(app.GraphicsPanel, 'push');
            app.EnergyButton.ButtonPushedFcn = createCallbackFcn(app, @EnergyButtonPushed, true);
            app.EnergyButton.Text = 'Energy';
            app.SpinButton = uibutton(app.GraphicsPanel, 'push');
            app.SpinButton.ButtonPushedFcn = createCallbackFcn(app, @SpinButtonPushed, true);
            app.SpinButton.Text = 'Spin';
            app.TemperatureButton = uibutton(app.GraphicsPanel, 'push');
            app.TemperatureButton.ButtonPushedFcn = createCallbackFcn(app, @TemperatureButtonPushed, true);
            app.TemperatureButton.Text = 'Temperature';
            app.LogPanel = uipanel(app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure);
            app.RunSimulationButton = uibutton(app.LogPanel, 'push');
            app.RunSimulationButton.Text = 'Run Simulation';
            app.ExportDataButton = uibutton(app.LogPanel, 'push');
            app.ExportDataButton.Text = 'Export Data';
            app.TextArea = uitextarea(app.LogPanel);
            app.Lamp = uilamp(app.LogPanel);
            app.ParameterPanel = uipanel(app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure);
            app.ParameterTabGroup = uitabgroup(app.ParameterPanel);
            app.InitialConditionsTab = uitab(app.ParameterTabGroup);
            app.InitialConditionsTab.Title = 'Initial Conditions';
            app.xLaunchCoordinateEditFieldLabel = uilabel(app.InitialConditionsTab);
            app.xLaunchCoordinateEditFieldLabel.Text = 'x Launch Coordinate';
            app.xLaunchCoordinateEditField = uieditfield(app.InitialConditionsTab, 'text');
            app.yLaunchCoordinateEditFieldLabel = uilabel(app.InitialConditionsTab);
            app.yLaunchCoordinateEditFieldLabel.Text = 'y Launch Coordinate';
            app.yLaunchCoordinateEditField = uieditfield(app.InitialConditionsTab, 'text');
            app.zLaunchCoordinateEditFieldLabel = uilabel(app.InitialConditionsTab);
            app.zLaunchCoordinateEditFieldLabel.Text = 'z Launch Coordinate';
            app.zLaunchCoordinateEditField = uieditfield(app.InitialConditionsTab, 'text');
            app.xVelocityComponentEditFieldLabel = uilabel(app.InitialConditionsTab);
            app.xVelocityComponentEditFieldLabel.Text = 'x Velocity Component';
            app.xVelocityComponentEditField = uieditfield(app.InitialConditionsTab, 'text');
            app.yVelocityComponentEditFieldLabel = uilabel(app.InitialConditionsTab);
            app.yVelocityComponentEditFieldLabel.Text = 'y Velocity Component';
            app.yVelocityComponentEditField = uieditfield(app.InitialConditionsTab, 'text');
            app.zVelocityComponentEditFieldLabel = uilabel(app.InitialConditionsTab);
            app.zVelocityComponentEditFieldLabel.Text = 'z Velocity Component';
            app.zVelocityComponentEditField = uieditfield(app.InitialConditionsTab, 'text');
            app.iSpinComponentEditFieldLabel = uilabel(app.InitialConditionsTab);
            app.iSpinComponentEditFieldLabel.Text = 'i Spin Component';
            app.iSpinComponentEditField = uieditfield(app.InitialConditionsTab, 'text');
            app.jSpinComponentEditFieldLabel = uilabel(app.InitialConditionsTab);
            app.jSpinComponentEditFieldLabel.Text = 'j Spin Component';
            app.jSpinComponentEditField = uieditfield(app.InitialConditionsTab, 'text');
            app.kSpinComponentEditFieldLabel = uilabel(app.InitialConditionsTab);
            app.kSpinComponentEditFieldLabel.Text = 'k Spin Component';
            app.kSpinComponentEditField = uieditfield(app.InitialConditionsTab, 'text');
            app.PodTemperatureEditFieldLabel = uilabel(app.InitialConditionsTab);
            app.PodTemperatureEditFieldLabel.Text = 'Pod Temperature';
            app.PodTemperatureEditField = uieditfield(app.InitialConditionsTab, 'text');
            app.PodInformationTab = uitab(app.ParameterTabGroup);
            app.PodInformationTab.Title = 'Pod Information';
            app.TotalMassEditFieldLabel = uilabel(app.PodInformationTab);
            app.TotalMassEditFieldLabel.Text = 'Total Mass';
            app.TotalMassEditField = uieditfield(app.PodInformationTab, 'text');
            app.ShearModulusEditField_2Label = uilabel(app.PodInformationTab);
            app.ShearModulusEditField_2Label.Text = 'Shear Modulus';
            app.ShearModulusEditField_2 = uieditfield(app.PodInformationTab, 'text');
            app.YoungsModulusEditField_2Label = uilabel(app.PodInformationTab);
            app.YoungsModulusEditField_2Label.Text = 'Young''s Modulus';
            app.YoungsModulusEditField_2 = uieditfield(app.PodInformationTab, 'text');
            app.MomentofInertiaEditFieldLabel = uilabel(app.PodInformationTab);
            app.MomentofInertiaEditFieldLabel.Text = 'Moment of Inertia';
            app.MomentofInertiaEditField = uieditfield(app.PodInformationTab, 'text');
            app.DiameterEditFieldLabel = uilabel(app.PodInformationTab);
            app.DiameterEditFieldLabel.Text = 'Diameter';
            app.DiameterEditField = uieditfield(app.PodInformationTab, 'text');
            app.HeatCapacityEditFieldLabel = uilabel(app.PodInformationTab);
            app.HeatCapacityEditFieldLabel.Text = 'Heat Capacity';
            app.HeatCapacityEditField = uieditfield(app.PodInformationTab, 'text');
            app.DensityEditFieldLabel = uilabel(app.PodInformationTab);
            app.DensityEditFieldLabel.Text = 'Density';
            app.DensityEditField = uieditfield(app.PodInformationTab, 'text');
            app.MaximumServiceTemperatureEditFieldLabel = uilabel(app.PodInformationTab);
            app.MaximumServiceTemperatureEditFieldLabel.Text = 'Maximum Service Temperature';
            app.MaximumServiceTemperatureEditField = uieditfield(app.PodInformationTab, 'text');
            app.MaximumTemperatureDifferentialEditFieldLabel = uilabel(app.PodInformationTab);
            app.MaximumTemperatureDifferentialEditFieldLabel.Text = 'Maximum Temperature Differential';
            app.MaximumTemperatureDifferentialEditField = uieditfield(app.PodInformationTab, 'text');
            app.MaximumDeformationEditFieldLabel = uilabel(app.PodInformationTab);
            app.MaximumDeformationEditFieldLabel.Text = 'Maximum Deformation';
            app.MaximumDeformationEditField = uieditfield(app.PodInformationTab, 'text');
            app.YieldStrengthEditFieldLabel = uilabel(app.PodInformationTab);
            app.YieldStrengthEditFieldLabel.Text = 'Yield Strength';
            app.YieldStrengthEditField = uieditfield(app.PodInformationTab, 'text');
            app.FractureToughnessEditFieldLabel = uilabel(app.PodInformationTab);
            app.FractureToughnessEditFieldLabel.Text = 'Fracture Toughness';
            app.FractureToughnessEditField = uieditfield(app.PodInformationTab, 'text');
            app.MaximumForceEditFieldLabel = uilabel(app.PodInformationTab);
            app.MaximumForceEditFieldLabel.Text = 'Maximum Force';
            app.MaximumForceEditField = uieditfield(app.PodInformationTab, 'text');
            app.TensileStrengthEditFieldLabel = uilabel(app.PodInformationTab);
            app.TensileStrengthEditFieldLabel.Text = 'Tensile Strength';
            app.TensileStrengthEditField = uieditfield(app.PodInformationTab, 'text');
            app.MaximumForcecEditFieldLabel = uilabel(app.PodInformationTab);
            app.MaximumForcecEditFieldLabel.Text = 'Maximum Force (c)';
            app.MaximumForcecEditField = uieditfield(app.PodInformationTab, 'text');
            app.MaximumServiceTemperaturecEditFieldLabel = uilabel(app.PodInformationTab);
            app.MaximumServiceTemperaturecEditFieldLabel.Text = 'Maximum Service Temperature (c)';
            app.MaximumServiceTemperaturecEditField = uieditfield(app.PodInformationTab, 'text');
            app.MaximumAngularAccelerationcEditFieldLabel = uilabel(app.PodInformationTab);
            app.MaximumAngularAccelerationcEditFieldLabel.Text = 'Maximum Angular Acceleration (c)';
            app.MaximumAngularAccelerationcEditField = uieditfield(app.PodInformationTab, 'text');
            app.StiffnessEditFieldLabel = uilabel(app.PodInformationTab);
            app.StiffnessEditFieldLabel.Text = 'Stiffness';
            app.StiffnessEditField = uieditfield(app.PodInformationTab, 'text');
            app.EnvironmentalConditionsTab = uitab(app.ParameterTabGroup);
            app.EnvironmentalConditionsTab.Title = 'Environmental Conditions';
            app.AtmosphericDensityEditFieldLabel = uilabel(app.EnvironmentalConditionsTab);
            app.AtmosphericDensityEditFieldLabel.Text = 'Atmospheric Density';
            app.AtmosphericDensityEditField = uieditfield(app.EnvironmentalConditionsTab, 'text');
            app.GravitationalAccelerationEditFieldLabel = uilabel(app.EnvironmentalConditionsTab);
            app.GravitationalAccelerationEditFieldLabel.Text = 'Gravitational Acceleration';
            app.GravitationalAccelerationEditField = uieditfield(app.EnvironmentalConditionsTab, 'text');
            app.ShearModulusEditFieldLabel = uilabel(app.EnvironmentalConditionsTab);
            app.ShearModulusEditFieldLabel.Text = 'Shear Modulus';
            app.ShearModulusEditField = uieditfield(app.EnvironmentalConditionsTab, 'text');
            app.YoungsModulusEditFieldLabel = uilabel(app.EnvironmentalConditionsTab);
            app.YoungsModulusEditFieldLabel.Text = 'Young''s Modulus';
            app.YoungsModulusEditField = uieditfield(app.EnvironmentalConditionsTab, 'text');
            app.SurfaceTemperatureEditFieldLabel = uilabel(app.EnvironmentalConditionsTab);
            app.SurfaceTemperatureEditFieldLabel.Text = 'Surface Temperature';
            app.SurfaceTemperatureEditField = uieditfield(app.EnvironmentalConditionsTab, 'text');
            app.CoefficientofFrictionpEditFieldLabel = uilabel(app.EnvironmentalConditionsTab);
            app.CoefficientofFrictionpEditFieldLabel.Text = 'Coefficient of Friction (p)';
            app.CoefficientofFrictionpEditField = uieditfield(app.EnvironmentalConditionsTab, 'text');
            app.RollingCoefficientofFrictionpEditFieldLabel = uilabel(app.EnvironmentalConditionsTab);
            app.RollingCoefficientofFrictionpEditFieldLabel.Text = 'Rolling Coefficient of Friction (p)';
            app.RollingCoefficientofFrictionpEditField = uieditfield(app.EnvironmentalConditionsTab, 'text');
            app.CoefficientofRestitutionpEditFieldLabel = uilabel(app.EnvironmentalConditionsTab);
            app.CoefficientofRestitutionpEditFieldLabel.Text = 'Coefficient of Restitution (p)';
            app.CoefficientofRestitutionpEditField = uieditfield(app.EnvironmentalConditionsTab, 'text');
            app.ThermalConductivitypEditFieldLabel = uilabel(app.EnvironmentalConditionsTab);
            app.ThermalConductivitypEditFieldLabel.Text = 'Thermal Conductivity (p)';
            app.ThermalConductivitypEditField = uieditfield(app.EnvironmentalConditionsTab, 'text');
            app.TerrainHeightEditFieldLabel = uilabel(app.EnvironmentalConditionsTab);
            app.TerrainHeightEditFieldLabel.Text = 'Terrain Height';
            app.TerrainHeightEditField = uieditfield(app.EnvironmentalConditionsTab, 'text');
            app.TerrainWidthEditFieldLabel = uilabel(app.EnvironmentalConditionsTab);
            app.TerrainWidthEditFieldLabel.Text = 'Terrain Width';
            app.TerrainWidthEditField = uieditfield(app.EnvironmentalConditionsTab, 'text');
            app.Image = uiimage(app.EnvironmentalConditionsTab);
            app.Image.ImageSource = 'Surface.png';
            app.SurfacepngLabel = uilabel(app.EnvironmentalConditionsTab);
            app.SurfacepngLabel.Text = 'Surface.png';
            app.ProgramDataTab = uitab(app.ParameterTabGroup);
            app.ProgramDataTab.Title = 'Program Data';
            app.MinimumPerpendicularMagnitudeEditFieldLabel = uilabel(app.ProgramDataTab);
            app.MinimumPerpendicularMagnitudeEditFieldLabel.Text = 'Minimum Perpendicular Magnitude';
            app.MinimumPerpendicularMagnitudeEditField = uieditfield(app.ProgramDataTab, 'text');
            app.TimeSegmentLengthEditFieldLabel = uilabel(app.ProgramDataTab);
            app.TimeSegmentLengthEditFieldLabel.Text = 'Time Segment Length';
            app.TimeSegmentLengthEditField = uieditfield(app.ProgramDataTab, 'text');
            app.TerrainGraphIntervalEditFieldLabel = uilabel(app.ProgramDataTab);
            app.TerrainGraphIntervalEditFieldLabel.Text = 'Terrain Graph Interval';
            app.TerrainGraphIntervalEditField = uieditfield(app.ProgramDataTab, 'text');
            app.TerrainGraphMaximumEditFieldLabel = uilabel(app.ProgramDataTab);
            app.TerrainGraphMaximumEditFieldLabel.Text = 'Terrain Graph Maximum';
            app.TerrainGraphMaximumEditField = uieditfield(app.ProgramDataTab, 'text');
            app.TerrainGraphMinimumEditFieldLabel = uilabel(app.ProgramDataTab);
            app.TerrainGraphMinimumEditFieldLabel.Text = 'Terrain Graph Minimum';
            app.TerrainGraphMinimumEditField = uieditfield(app.ProgramDataTab, 'text');
            app.TimeGraphIntervalEditFieldLabel = uilabel(app.ProgramDataTab);
            app.TimeGraphIntervalEditFieldLabel.Text = 'Time Graph Interval';
            app.TimeGraphIntervalEditField = uieditfield(app.ProgramDataTab, 'text');
            app.MaximumiIterationsEditFieldLabel = uilabel(app.ProgramDataTab);
            app.MaximumiIterationsEditFieldLabel.Text = 'Maximum i Iterations';
            app.MaximumiIterationsEditField = uieditfield(app.ProgramDataTab, 'text');
            app.MaximumjIterationsEditFieldLabel = uilabel(app.ProgramDataTab);
            app.MaximumjIterationsEditFieldLabel.Text = 'Maximum j Iterations';
            app.MaximumjIterationsEditField = uieditfield(app.ProgramDataTab, 'text');
            app.MinimumEdgeDistanceEditFieldLabel = uilabel(app.ProgramDataTab);
            app.MinimumEdgeDistanceEditFieldLabel.Text = 'Minimum Edge Distance';
            app.MinimumEdgeDistanceEditField = uieditfield(app.ProgramDataTab, 'text');
            app.MinimumSurfaceDistanceEditFieldLabel = uilabel(app.ProgramDataTab);
            app.MinimumSurfaceDistanceEditFieldLabel.Text = 'Minimum Surface Distance';
            app.MinimumSurfaceDistanceEditField = uieditfield(app.ProgramDataTab, 'text');
            app.MinimumVelocityMagnitudeEditFieldLabel = uilabel(app.ProgramDataTab);
            app.MinimumVelocityMagnitudeEditFieldLabel.Text = 'Minimum Velocity Magnitude';
            app.MinimumVelocityMagnitudeEditField = uieditfield(app.ProgramDataTab, 'text');
            app.MinimumCollisionDistanceEditFieldLabel = uilabel(app.ProgramDataTab);
            app.MinimumCollisionDistanceEditFieldLabel.Text = 'Minimum Collision Distance';
            app.MinimumCollisionDistanceEditField = uieditfield(app.ProgramDataTab, 'text');
            app.MinimumTimeofFlightEditFieldLabel = uilabel(app.ProgramDataTab);
            app.MinimumTimeofFlightEditFieldLabel.Text = 'Minimum Time of Flight';
            app.MinimumTimeofFlightEditField = uieditfield(app.ProgramDataTab, 'text');
            app.MaximumTimeofFlightEditFieldLabel = uilabel(app.ProgramDataTab);
            app.MaximumTimeofFlightEditFieldLabel.Text = 'Maximum Time of Flight';
            app.MaximumTimeofFlightEditField = uieditfield(app.ProgramDataTab, 'text');
            app.RangeofPerpendicularMagEditFieldLabel = uilabel(app.ProgramDataTab);
            app.RangeofPerpendicularMagEditFieldLabel.Text = 'Range of Perpendicular Mag.';
            app.RangeofPerpendicularMagEditField = uieditfield(app.ProgramDataTab, 'text');
            app.TimeSteppingIntervalEditFieldLabel = uilabel(app.ProgramDataTab);
            app.TimeSteppingIntervalEditFieldLabel.Text = 'Time "Stepping" Interval';
            app.TimeSteppingIntervalEditField = uieditfield(app.ProgramDataTab, 'text');
            app.BicubicInterpolationIntervalEditFieldLabel = uilabel(app.ProgramDataTab);
            app.BicubicInterpolationIntervalEditFieldLabel.Text = 'Bicubic Interpolation Interval';
            app.BicubicInterpolationIntervalEditField = uieditfield(app.ProgramDataTab, 'text');
            % Show the figure after all components are created
            app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IRIS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InteractiveResourceImpactSimulationJoshuaAngeUIFigure)
        end
    end
end